classdef GassDem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GassDemUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EffectiveMediumTab              matlab.ui.container.Tab
        FluidpropertiesPanel            matlab.ui.container.Panel
        CompressibilityBfEditFieldLabel  matlab.ui.control.Label
        CompressibilityBfEditField      matlab.ui.control.NumericEditField
        CompressibilityBfEditFieldLabel_2  matlab.ui.control.Label
        ViscosityEditFieldLabel         matlab.ui.control.Label
        ViscosityEditField              matlab.ui.control.NumericEditField
        FluidViscosityPasEditFieldLabel  matlab.ui.control.Label
        VpEditFieldLabel                matlab.ui.control.Label
        VpEditField                     matlab.ui.control.NumericEditField
        CompressibilityBfEditFieldLabel_4  matlab.ui.control.Label
        DensityEditFieldLabel           matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        FluidDensitygcm3EditFieldLabel  matlab.ui.control.Label
        Bf1KfKfBulkmodulusGPaLabel      matlab.ui.control.Label
        FluidCompressibilityEGLabel     matlab.ui.control.Label
        FluidViscosityEGLabel           matlab.ui.control.Label
        FluidVpEGLabel                  matlab.ui.control.Label
        FluidDensityEGLabel             matlab.ui.control.Label
        MaxerrorforGreensTensoreg01EditFieldLabel  matlab.ui.control.Label
        MaxerrorforGreensTensoreg01EditField  matlab.ui.control.NumericEditField
        TwocomponentsystemofAandBforDEMcalculationPanel  matlab.ui.container.Panel
        HostmediumElasticConstantsEditField  matlab.ui.control.EditField
        HostmediumDensityEditField      matlab.ui.control.NumericEditField
        AHostmediumEditFieldLabel       matlab.ui.control.Label
        HostmediumNameEditField         matlab.ui.control.EditField
        InclusionElasticConstantsEditField  matlab.ui.control.EditField
        InclusionDensityEditField       matlab.ui.control.NumericEditField
        BInclusionegfluidEditFieldLabel  matlab.ui.control.Label
        InclusionNameEditField          matlab.ui.control.EditField
        PhaseLabel                      matlab.ui.control.Label
        NameLabel                       matlab.ui.control.Label
        ElasticconstantsfiletxtLabel    matlab.ui.control.Label
        Densitygcm3Label                matlab.ui.control.Label
        NoteVoigtelastictensorinGPaLabel  matlab.ui.control.Label
        InputfilesdirectoryEditFieldLabel  matlab.ui.control.Label
        InputfilesdirectoryEditField    matlab.ui.control.EditField
        GassDemdirectoryEditFieldLabel  matlab.ui.control.Label
        GassDemdirectoryEditField       matlab.ui.control.EditField
        InclusionShapeandOrientationTab  matlab.ui.container.Tab
        EllipsoidsemiaxeslengthsA1A2A3ButtonGroup  matlab.ui.container.ButtonGroup
        Sphere111Button                 matlab.ui.control.RadioButton
        EllipsoidButton                 matlab.ui.control.RadioButton
        EllipsoidA1EditField            matlab.ui.control.NumericEditField
        EllipsoidA2EditField            matlab.ui.control.NumericEditField
        EllipsoidA3EditField            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        OrientationofshapeellipsoidsemiaxesButtonGroup  matlab.ui.container.ButtonGroup
        ParalleltoelastictensoraxesButton  matlab.ui.control.RadioButton
        ParalleltouserdefinedorientationSPOButton  matlab.ui.control.RadioButton
        Label_3                         matlab.ui.control.Label
        SPOLabel                        matlab.ui.control.Label
        A1EditFieldLabel                matlab.ui.control.Label
        A1AzimuthdegreeEditField        matlab.ui.control.NumericEditField
        A1InclinationdegreeEditField    matlab.ui.control.NumericEditField
        A3InclinationdegreeEditField    matlab.ui.control.NumericEditField
        A3EditFieldLabel                matlab.ui.control.Label
        A3AzimuthdegreeEditField        matlab.ui.control.NumericEditField
        A1AzimuthEditFieldLabel         matlab.ui.control.Label
        A1InclinationEditFieldLabel     matlab.ui.control.Label
        Parallel2XUIAxes                matlab.ui.control.UIAxes
        CheckButton                     matlab.ui.control.Button
        OrientatonisOKPanel             matlab.ui.container.Panel
        Check1AngleA1toA3EditFieldLabel  matlab.ui.control.Label
        Check1AngleA1toA3EditField      matlab.ui.control.NumericEditField
        Check2AngleA1toA3EditFieldLabel  matlab.ui.control.Label
        Check2AngleA1toA3EditField      matlab.ui.control.NumericEditField
        DetRLabel                       matlab.ui.control.Label
        Check3DetRshouldbe1Label        matlab.ui.control.Label
        Check3DetRshouldbe1EditField    matlab.ui.control.NumericEditField
        FixedorientationofshapeellipsoidaxesLabel  matlab.ui.control.Label
        A1AZLabel                       matlab.ui.control.Label
        A1AZEditField                   matlab.ui.control.NumericEditField
        INCEditFieldLabel               matlab.ui.control.Label
        A1INCEditField                  matlab.ui.control.NumericEditField
        A2AZLabel                       matlab.ui.control.Label
        A2AZEditField                   matlab.ui.control.NumericEditField
        INCEditField_2Label             matlab.ui.control.Label
        A2INCEditField                  matlab.ui.control.NumericEditField
        A3AZLabel                       matlab.ui.control.Label
        A3AZEditField                   matlab.ui.control.NumericEditField
        INCEditField_3Label             matlab.ui.control.Label
        A3INCEditField                  matlab.ui.control.NumericEditField
        ShapeellipsoidrotationmatrixLabel  matlab.ui.control.Label
        ShapeEllipsoidRotationMatrix11EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix12EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix13EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix21EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix22EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix23EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix31EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix32EditField  matlab.ui.control.NumericEditField
        ShapeEllipsoidRotationMatrix33EditField  matlab.ui.control.NumericEditField
        repeattoroundofferrorsLabel     matlab.ui.control.Label
        AZAzimuthINCInclinationLabel    matlab.ui.control.Label
        DEMAnalysisTab                  matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        Maxvolumefractionofinclusion01EditFieldLabel  matlab.ui.control.Label
        Maxvolumefractionofinclusion01EditField  matlab.ui.control.NumericEditField
        FilenameanddirectoryforDEMresultsPanel  matlab.ui.container.Panel
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        CurrentstatusPanel              matlab.ui.container.Panel
        InclusionisEditFieldLabel       matlab.ui.control.Label
        InclusionisEditField            matlab.ui.control.EditField
        VolumeincrementstepsizedVbEditFieldLabel  matlab.ui.control.Label
        VolumeincrementstepsizedVbEditField  matlab.ui.control.NumericEditField
        TotalstepsEditFieldLabel        matlab.ui.control.Label
        TotalstepsEditField             matlab.ui.control.NumericEditField
        ProgressEditField               matlab.ui.control.EditField
        ProgressLabel                   matlab.ui.control.Label
        ProgressLamp                    matlab.ui.control.Lamp
        FigureconfigurationPanel        matlab.ui.container.Panel
        VolumefractionsEditFieldLabel   matlab.ui.control.Label
        VpMinEditField                  matlab.ui.control.NumericEditField
        VpMaxEditField                  matlab.ui.control.NumericEditField
        VolumefractionsEditFieldLabel_2  matlab.ui.control.Label
        VolumefractionsMaxEditField     matlab.ui.control.NumericEditField
        VolumefractionsEditFieldLabel_3  matlab.ui.control.Label
        VolumefractionsMinEditField     matlab.ui.control.NumericEditField
        VolumefractionsEditFieldLabel_4  matlab.ui.control.Label
        StructuraldirectionLabel_2      matlab.ui.control.Label
        ShearwavedirectionDropDownLabel  matlab.ui.control.Label
        ShearwavedirectionDropDown      matlab.ui.control.DropDown
        StructuraldirectionDropDownLabel  matlab.ui.control.Label
        StructuraldirectionDropDown     matlab.ui.control.DropDown
        VolumefractionsEditFieldLabel_5  matlab.ui.control.Label
        VsMinEditField                  matlab.ui.control.NumericEditField
        VsMaxEditField                  matlab.ui.control.NumericEditField
        StructuraldirectionLabel_3      matlab.ui.control.Label
        QmaxInvEditFieldLabel           matlab.ui.control.Label
        QmaxInvMinEditField             matlab.ui.control.NumericEditField
        QmaxInvMaxEditField             matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        Note1QmaxisplottedassemilogscaleLabel  matlab.ui.control.Label
        DEMresultsVpUIAxes              matlab.ui.control.UIAxes
        DEMresultsVsUIAxes              matlab.ui.control.UIAxes
        NoteHHighfrequencyLLowfrequencyLabel  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        AboutGassDemTab                 matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        InputsTab                       matlab.ui.container.Tab
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        OutputsTab                      matlab.ui.container.Tab
        OutputfilesPanel                matlab.ui.container.Panel
        OutputFilesDescriptionUITable   matlab.ui.control.Table
        HelpOutputsNoteLabel            matlab.ui.control.Label
        CodesTab                        matlab.ui.container.Tab
        Label_11                        matlab.ui.control.Label
    end


    properties (Access = public)
        vpxl % Low frequency Vp in X direction  
        vpyl % Low frequency Vp in Y direction
        vpzl % Low frequency Vp in Z direction
        vs1xl % Low frequency fast Vs in X direction
        vs1yl % Low frequency fast Vs in Y direction
        vs1zl % Low frequency fast Vs in Z direction
        vs2xl % Low frequency slow Vs in X direction
        vs2yl % Low frequency fast Vs in Y direction
        vs2zl % Low frequency fast Vs in Z direction
        vpxh % High frequency Vp in X direction  
        vpyh % High frequency Vp in Y direction 
        vpzh % High frequency Vp in Z direction 
        vs1xh % High frequency fast Vs in X direction
        vs1yh % High frequency fast Vs in Y direction
        vs1zh % High frequency fast Vs in Z direction
        vs2xh % High frequency slow Vs in X direction
        vs2yh % High frequency slow Vs in Y direction
        vs2zh % High frequency slow Vs in Z direction
        Vb % Inclusion volume fraction
        qpx % Qmax^(-1) of Vp in X direction 
        qpy % Qmax^(-1) of Vp in Y direction
        qpz % Qmax^(-1) of Vp in Z direction
        qs1x % Qmax^(-1) of fast Vs in X direction
        qs1y % Qmax^(-1) of fast Vs in Y direction
        qs1z % Qmax^(-1) of fast Vs in Z direction
        qs2x % Qmax^(-1) of slow Vs in X direction
        qs2y % Qmax^(-1) of slow Vs in Y direction
        qs2z % Qmax^(-1) of slow Vs in Z direction
    end

    properties (Access = private)
    end

    methods (Access = private)
        function [r] = azr(app,al,xl,af,xf)

            % AZ/INC Lineation & Foliation -> Rij 
            %
            % ellipsoid semi-axis A1
            %    al: Azimuth 
            %    xl: Inclination 
            % ellipsoid semi-axis A3
            %    af: Azimuth 
            %    xf: Inclination 
            
            r   = zeros(3,3); 
            deg = 180/pi;
            
            [x1,y1,z1] = xyz(app,al,xl);
            [x3,y3,z3] = xyz(app,af,xf);
            
            [x1,y1,z1] = dircos(app,x1,y1,z1);
            [x3,y3,z3] = dircos(app,x3,y3,z3);
            
            dot = x1*x3 + y1*y3 + z1*z3;
            
            if dot > 1
                dot = 1; 
            elseif dot < -1
                dot = -1; 
            end
            
            value = acos(dot)*deg; 
            app.Check1AngleA1toA3EditField.Value = value;
            
            % repeat to round-off errors
            [x2,y2,z2] = cross(app,x3,y3,z3,x1,y1,z1);
            [x2,y2,z2] = dircos(app,x2,y2,z2);
            [x3,y3,z3] = cross(app,x1,y1,z1,x2,y2,z2);
            [x1,y1,z1] = cross(app,x2,y2,z2,x3,y3,z3);
            [x2,y2,z2] = cross(app,x3,y3,z3,x1,y1,z1);
            
            dot = x1*x3 + y1*y3 + z1*z3;
            
            if dot > 1
                dot = 1; 
            elseif dot < -1
                dot = -1; 
            end
            
            value = acos(dot)*deg;
            app.Check2AngleA1toA3EditField.Value = value;
                        
            % stored by rows
            r(1,1) = x1;
            r(1,2) = y1;
            r(1,3) = z1;
            r(2,1) = x2;
            r(2,2) = y2;
            r(2,3) = z2;
            r(3,1) = x3;
            r(3,2) = y3;
            r(3,3) = z3;
            
            rdet = det(r);
            app.Check3DetRshouldbe1EditField.Value = rdet;
            
        end   

        function [az,xinc] = azi(app,x,y,z,ihemi)
            
            % Convert cartesian to geographic
            %
            % x,y,z --> az,xinc
            % x = North y = West z = Vertical
            % x,y,z dir cosines - anticlockwise +ve right handed
            % xinc = 90 vertical, xinc = 0 horizontal
            % convert to +ve HEMIsphere
            
            if z < 0
                xt = -x;
                yt = -y;
                zt = -z;
            else
                xt = x;
                yt = y;
                zt = z;
            end
            
            % convert to dir.cos
            [xt,yt,zt] = dircos(app,xt,yt,zt);
            
            % az anticlockwise +ve
            [az] = arctan(app,xt,yt);
            
            % convert to clockwise +ve (geographic) - left hand
            az = 360 - az;
            
            % polar angle (vertical to pole)
            r = sqrt(xt^2 + yt^2 + zt^2);
            
            if zt == 0
                polar = 0;
            else
                polar = zt/r;
            end
            
            [~,xinc] = arccos(app,polar);
            
            % convert to inclination (horizontal to pole)
            xinc = 90 - xinc;
            
            % if ihemi = -1 then make a distinction between +/-ve hemispheres
            if ihemi == -1 && z < 0
                az = az + 180;
                xinc = -xinc;
            end
            if az > 360
                az = az - 360; 
            end
        end


        function [x,y,z] = dircos(app,x,y,z)
            % Convert to direction cosines
            
            small = 0.0001;
            xm = sqrt(x^2 + y^2 + z^2);
            
            if abs(x) > small
                x = x/xm; 
            elseif abs(x) <= small
                x = 0; 
            end
            
            if abs(y) > small
                y = y/xm; 
            elseif abs(y) <= small
                y = 0; 
            end
            
            if abs(z) > small
                z = z/xm; 
            elseif abs(z) <= small
                z = 0; 
            end
        end
        
        function [x,y,z] = xyz(app,az,xinc)
        
            % az,xinc --> x,y,z (direction cosines)            
            %
            % Right handed system 
            %       x = North y = West z = Vertical
            
            rad  = pi/180;
            raz  = az*rad;
            rinc = xinc*rad;
            
            x = cos(raz)*cos(rinc);
            y = -sin(raz)*cos(rinc);
            z = sin(rinc);       
        
        end
        
        function [x3,y3,z3] = cross(app,x1,y1,z1,x2,y2,z2)

            % Vector cross-product 1 x 2 = 3 right-handed
            
            x3 = (y1*z2) - (z1*y2);
            y3 = (z1*x2) - (x1*z2);
            z3 = (x1*y2) - (y1*x2);

        end
        
        function [angle] = arctan(app,x,y)

            % arctan(y/x) = angle 0-360 degrees anticlockwise
            % avoids underflow problems with Microsoft F77 compiler
            
            small = 0.00001;
            deg = 180/pi;
            ax = abs(x);
            ay = abs(y);
            
            % Exceptions 0,90,180,270
            if ay < small && ax < small 
                angle = 0; 
                return;
            elseif ay <= small && x > 0
                angle = 0;
                return;
            elseif ax <= small && y > 0
                angle = 90; 
                return;
            elseif ay <= small && x < 0
                angle = 180; 
                return;
            elseif ax <= small && y < 0
                angle = 270; 
                return;
            end
            
            % General case
            ratio = y/x;
            if ratio > 350
                ratio = 350; 
            elseif ratio < -350
                ratio = -350; 
            end
            
            angle = deg*atan(ratio);
            
            if x > 0 && y > 0 
                return;
            elseif x < 0 && y > 0
                angle = 180 + angle; 
                return;
            elseif x < 0 && y < 0
                angle = 180 + angle; 
                return;
            elseif x > 0 && y < 0
                angle = 360 + angle;
            end


        end
        
        function [x,angle] = arccos(app,x)
            
            if x > 1
                x = 1; 
            elseif x < -1
                x = -1; 
            end
            
            angle = acos(x)*180/pi;


        end
        
        function [re,az1,xinc1,az2,xinc2,az3,xinc3] = orientation(app,al,xl,af,xf,ihemi)
            
            % User defined shape ellipsoid orientation
            % A1 should be 90 degrees to A3
            % 
            % Inputs
            %       al    - Azimuth (degree) of ellipsoid semi-axis A1
            %       xl    - Inclination (degree) of ellipsoid semi-axis A1
            %       af    - Azimuth (degree) of ellipsoid semi-axis A3
            %       xf    - Inclination (degree) of ellipsoid semi-axis A3
            %
            %       ihemi - Hemisphere Convention: +1 = dip +VE upper hemisphere
            %                                      -1 = dip +VE lower hemisphere
            % Outputs
            %       re     - Rotation matrix considering shape ellipsoid 
            %                          and user defined orientation (SPO) 
            %       az1    - Fixed azimuth of A1
            %       xinc1  - Fixed inclination of A1
            %       az2    - Fixed azimuth of A2
            %       xinc2  - Fixed inclination of A2
            %       az3    - Fixed azimuth of A3
            %       xinc3  - Fixed inclination of A3
             
            
            % X = L, Z = FN, Y = Z x X (L:lineation, FN:foliation normal)
            [re] = azr(app,al,xl,af,xf);
                        
            % az/inc of 3 axes of ellipsoid (rows of REij)
            % convert from dir.cos to AZ/INC
            %
            % re - Shape ellipsoid rotation matrix          
            
%             [az1,xinc1] = azi(app,re(1,1),re(2,1),re(3,1),ihemi);
%             [az2,xinc2] = azi(app,re(1,2),re(2,2),re(3,2),ihemi);
%             [az3,xinc3] = azi(app,re(1,3),re(2,3),re(3,3),ihemi);
            [az1,xinc1] = azi(app,re(1,1),re(1,2),re(1,3),ihemi);
            [az2,xinc2] = azi(app,re(2,1),re(2,2),re(2,3),ihemi);
            [az3,xinc3] = azi(app,re(3,1),re(3,2),re(3,3),ihemi);            
        end
        
        function [nb,x,w,ntcheb] = error4G(app,cm,errmax,eaxis)
            % Error analysis of Green's Tensor for medium Cij          
            %
            % Cm(6,6)  = Background medium
            % Errmax   = Max. Error % (0.1 is a typical value) on diagonal terms
            % eaxis(3) = Ellipsoid semi-axes
            % nb       = Number of iterations necessary to obtain
            %      error < max. error
            % ** revised June 1996 **
            
            gtold = zeros(6,6); 
            
            % loop over possible number of integration steps
            for inb = 1:100
                nb = inb*4;
                
                [ntcheb,x,w] = tcheb(app,nb);
                
                % Calculate Green's tensor
                [gt] = green3(app,cm,eaxis,x,w,ntcheb);
                
                diag = 0; 
                
                % upper diagonal of symmetric matrix
                for i = 1:6
                    for j = i:6            
                        % sum diagonal terms only to avoid rounding errors
                        % on off-diagonal terms in isotropic case
                        if j == i
                            diag = diag + abs(gt(j,i) - gtold(j,i))/abs(gt(j,i)); 
                        end
                        gtold(j,i) = gt(j,i);
                    end
                end
                
                % average difference of diagonal terms as percentage
                diagav = 100*diag/6;
                
                % quit loop if convergence
                if diagav < errmax
                    [ntcheb,x,w] = tcheb(app,nb); 
                    return;
                end
            end
        end
        
        function [ntcheb,x,w] = tcheb(app,nb)
            x  = zeros(1,nb/2);
            w  = zeros(1,nb/2);
                
            ntcheb = nb/2;
            x1 = 0;
            x2 = 1;
                
            [xp,wp] = gauleg(app,x1,x2,nb);
                
            for i = 1:nb/2
                x(i) = 0.5 - xp(i);
                w(i) = wp(i);
            end
        end
        
        
        function [G] = green3(app,C2,axis,x,w,ntcheb)
            
            % Green's tensor for Eshelby inclusion problem
            %   using extended analysis of Kinoshita and Mura (1971)
            %   phys. stat. sol. (a) Vol.5 pp.759-768
            %   for anisotropic inclusion and in anisotropic medium
            %   following Appendix of Lebensohn & Tome (1993)
            %   Acta metall. mater. Vol.41 pp.2611-2624.
            %
            % N.B. Green's Tensor for inclusion with axes parallel to
            %   the reference frame of the elastic medium (X1,X2,X3).
            % Based on routine by Ricardo Lebensohn & Gilles Canova
            %   modified for single site and Voigt tensors
            %
            % C2(6,6) = Matrix (background) stiffness Voigt tensor
            % G(6,6)  = Green's Voigt tensor
            % axes(3) = Ellipsoidal inclusion semi-axes
            
            ijkl = reshape([1,6,5,6,2,4,5,4,3],[3,3]);
            ijv  = reshape([1,2,3,2,3,1,1,2,3,3,1,2],[6,2]);
            
            G  = zeros(6,6);
            g2 = zeros(6,6);
            ck = zeros(3,3);
            cp = zeros(3,3);
            xk = zeros(1,3);
            
            % POINTS & WEIGHTS FOR GAUSS INTEGRATION
            nint = 2*ntcheb;
            
            xp = zeros(1,nint);
            wp = zeros(1,nint);
            
            for i = 1:ntcheb
                xp(i)        = 0.5 + x(i);
                wp(i)        = w(i);
                xp(i+ntcheb) = 0.5 - x(i);
                wp(i+ntcheb) = w(i);
            end
            
            % INTEGRATION INTERVALS: [0,PI/2][PI/2,PI] BOTH FOR THETA AND PHI
            pas = pi/2;
            
            stheta = zeros(nint,2);
            ctheta = zeros(nint,2);
            sphi   = zeros(nint,2);
            cphi   = zeros(nint,2);
            
            for in = 1:2
                xx1 = pas*(in-1);
                for ig = 1:nint
                    xx = xx1 + pas*xp(ig);        
                    
                    stheta(ig,in) = sin(xx);
                    ctheta(ig,in) = cos(xx);
                    sphi(ig,in)   = sin(xx);
                    cphi(ig,in)   = cos(xx);
                    
                end
            end
            
            % INITIALIZE SYMMETRIC Greens Tensor
            G(:,:) = 0;
            
            % BIG LOOP (OVER INTERVALS AND INTEGRATION POINTS)
            for it = 1:2
                for ip = 1:2
                    for jt = 1:nint
                        for jp = 1:nint
                            
                            sth = stheta(jt,it);
                            cth = ctheta(jt,it);
                            sph = sphi(jp,ip);
                            cph = cphi(jp,ip);
                            
                            % ALFA: VERSOR in K DIRECTION
                            xk(1) = sth*cph;
                            xk(2) = sth*sph;
                            xk(3) = cth;
            
                            % (k**2 tch_1 Tfourier(G))=f(alfa)
                            % Christoffel matrix
                            for i = 1:3
                                for k = 1:i
                                    ckik = 0;
                                    for j = 1:3
                                        for l = 1:3                                
                                            mm = ijkl(j,i);
                                            nn = ijkl(l,k);
                                            ckik = ckik+C2(nn,mm)*xk(j)*xk(l);                                
                                        end
                                    end
                                    ck(k,i) = ckik;
                                    ck(i,k) = ckik;
                                end
                            end
                            
                            cp(:,:) = ck(:,:);
            
                            [ck] = inv(cp);
                            
                            % Symmetric alfa*alfa*ck
                            % /2 instead of /4, because of integration
                            % in phi=[0,pi] instead of [0,2pi]
                            for i = 1:6
                                i1 = ijv(i,1);
                                i2 = ijv(i,2);
                                for j = 1:6
                                    j1 = ijv(j,1);
                                    j2 = ijv(j,2);
                                    g2(j,i) = ck(i1,j1)*xk(i2)*xk(j2) + ...
                                              ck(i2,j1)*xk(i1)*xk(j2) + ...
                                              ck(i1,j2)*xk(i2)*xk(j1) + ...
                                              ck(i2,j2)*xk(i1)*xk(j1);
                                    g2(j,i) = 0.5*g2(j,i);
                                end
                            end
            
                            % F(th,ph)/ro**6 CALCULATION
                            ro = sqrt((axis(1)*xk(1))^2 + ...
                                      (axis(2)*xk(2))^2 + ...
                                      (axis(3)*xk(3))^2);
                            alfa = 2*ro;
                            sgalfa = abs(alfa)/alfa;
                            t = (-alfa^3 + 6*ro*alfa^2 - 6*alfa*ro^2)*sgalfa;
                            f1 = 2*t/ro^6;
            
                            % sum
                            for i = 1:6
                                for j = 1:6
                                    G(j,i) = G(j,i) + g2(j,i)*sth*f1*wp(jt)*wp(jp);
                                end
                            end
                            
                            % END OF BIG LOOP
                        end
                    end
                end
            end
            
            fact = axis(1)*axis(2)*axis(3)/32/pi;
            
            G(:,:) = fact*pas*pas*G(:,:);
            
        end
        
        function [x,w] = gauleg(app,x1,x2,n)
            
            % Given the lower and upper limits of integration X1 and X2, and given N, 
            %   this routine returns 
            % arrays X and W of length N, containing the abscissas and weights of the 
            %   Gauss-Legendre 
            % N-point quadrature formula
            
            m = n/2;
            x = zeros(1,n);
            w = zeros(1,n);
            
            xm = 0.5*(x1+x2);
            xl = 0.5*(x2-x1);
            xn = n;
            
            for i = 1:m
                xi = i;
                z = cos(pi*(xi-0.25)/(xn+0.5));
                p1 = 1;
                p2 = 0;
              
                for j = 1:n
                    xj = j;
                    p3 = p2;
                    p2 = p1;
                    p1 = ((2*j-1)*z*p2-(xj-1)*p3)/xj;
                end
                pp = n*(z*p1-p2)/(z*z-1);
                z1 = z;
                z = z1-p1/pp;
              
                while abs(z-z1) > eps        
                    p1 = 1;
                    p2 = 0;
                    for j = 1:n
                        xj = j;
                        p3 = p2;
                        p2 = p1;
                        p1 = ((2*j-1)*z*p2-(xj-1)*p3)/xj;
                    end
                    pp = n*(z*p1-p2)/(z*z-1);
                    z1 = z;
                    z = z1-p1/pp;
                end
                 
                x(i) = xm - xl*z;
                x(n+1-i) = xm + xl*z;
                w(i) = 2*xl/((1-z*z)*pp*pp);
                w(n+1-i) = w(i);
              
            end

        end
        
        function [c] = rot(app,l1,l2,ijkl,r,ec)
            
            % Rotate elastic constants form crystal to spacial coordinates
            % Cijkl = Rip*Rjq*Rkr*Rls*Cpqrs
            
            c = zeros(6,6);
            d = zeros(6,6);
            
            d(:,:) = ec(:,:);
            
            % Rotate elastic constants form crystal to spacial coordinates
            % Cijkl = Rip*Rjq*Rkr*Rls*Cpqrs
            for m = 1:6
                i = l1(m);
                j = l2(m);
                % compute lower diagonal
                for n = 1:m
                    k = l1(n);
                    l = l2(n);
                    x = 0;
                    for lp = 1:3
                        y = 0;
                        for lq = 1:3
                            lt = ijkl(lp,lq);
                            y = y + r(j,lq)*(r(k,1)*(r(l,1)*d(lt,1) + ...
                                                     r(l,2)*d(lt,6) + ...
                                                     r(l,3)*d(lt,5)) ...
                                           + r(k,2)*(r(l,1)*d(lt,6) + ...
                                                     r(l,2)*d(lt,2) + ...
                                                     r(l,3)*d(lt,4)) ...
                                           + r(k,3)*(r(l,1)*d(lt,5) + ...
                                                     r(l,2)*d(lt,4) + ...
                                                     r(l,3)*d(lt,3)));
                        end
                        x = x + r(i,lp)*y;
                    end
                    c(m,n) = x;
                    % copy to upper diagonal
                    c(n,m) = x;
                end
            end
      
        end
        
        function [cdem] = difdem2(app,cdem,vb,cinc,gt,dvb)
            
            % Differential Effective Medium (DEM) based on method of
            % R. McLaughlin (1977) Int. J. Engng. Sci. Vol.15, pp.237-244.
            % B.E. Hornby et al. (1994) Geophysics Vol.59, pp.1570-1583.
            %
            % Cdem(6,6) = DEM stiffness tensor at Vb
            % Cinc(6,6) = Inclusion stiffness tensor
            % gt(6,6)   = Green's tensor for DEM
            
            cdem = cdem';
            cinc = cinc';
            gt   = gt';
            
            iden  = zeros(6,6);
            am    = zeros(6,6);
            cdevm = zeros(6,6);
            
            % Iij - cartesian idenity matrix
            for i = 1:6
                iden(i,i) = 1;
            end
            
            % Transform all Voigt tensors to cartesian Kelvin matrices (or EigenTensors)
            [cdemm] = Voigt2Kelvin(app,cdem);
            [cincm] = Voigt2Kelvin(app,cinc);
            [gm]    = Voigt2Kelvin(app,gt);
            
            
            % I+G(Ci-Cdem)
            for i = 1:6
                for j = 1:6
                    am(i,j) = iden(i,j);
                    for k = 1:6
                        am(i,j) = am(i,j) + gm(i,k)*(cincm(k,j)-cdemm(k,j));
                    end
                end
            end
            
            
            % Am = inv(I+G(Ci-Cdem))
            am = inv(am);
            
            % Cdevm=(Ci-Cdem)*inv(I+G(Ci-Cdem))
            for i = 1:6
                for j = 1:6
                    cdevm(i,j) = 0;
                    for k = 1:6
                        cdevm(i,j) = cdevm(i,j) + (cincm(i,k)-cdemm(i,k))*am(k,j);
                    end
                end
            end
            
            % Cdemm -> Cdem
            [cdev] = Kelvin2Voigt(app,cdevm);
            
            % Cdem(Vb+dVb) = Cdem(Vb)+dVb*f*Cdev(i,j) - simple Euler method
            f = 1/(1-vb);
            for i = 1:6
                for j = 1:6
                    cdem(i,j) = cdem(i,j) + dvb*f*cdev(i,j);
                end
            end
            
            cdem = cdem';

      
        end
        
        
        function [Kelvin] = Voigt2Kelvin(app,Voigt)
            
            % The Voigt matrix does not preserves the norm of the tensor.
            % Converts Voigt to Kelvin matrix and Kelvin to Voigt matrix
            % The Kelvin elastic matrix preserves the norm of the tensor
            % and its eigen-values and vectors have physical meaning.
            % 
            % All mathematical matrix functions can be applied to Kelvin matrix 
            %
            % References
            % W.K. Thomson (Lord Kelvin),1856 
            %   Elements of a mathematical theory of elasticity,
            %   Philos. Trans. R. Soc. 146, 481-498.
            % W.K. Thomson (Lord Kelvin),1856 
            %   On six principal strains of an elastic solid, 
            %   Phil. Trans. R. Soc., 166, 495-498.
            % W.K. Thomson (Lord Kelvin),1878 
            %   Mathematical theory of elasticity,
            %   Encycl. Br. 7, 819-825. 
            %
            % INPUT
            % Voigt matrix
            % 
            % OUTPUT
            % Kelvin matrix
            %
            % David Mainprice (18/07/2018)
            %**************************************************************************
            % Matrix conversion Voigt to Kelvin = inv(Kelvin2Voigt)
            Voigt2KelvinMatrix = [[  1      0      0     0      0       0];  ...
                                 [   0      1      0     0      0       0];  ...
                                 [   0      0      1     0      0       0];  ...
                                 [   0      0      0   sqrt(2)  0       0];  ...
                                 [   0      0      0     0    sqrt(2)   0];  ...
                                 [   0      0      0     0      0  sqrt(2)]];
            % Matrix Voigt to Kelvin method  
            Kelvin = Voigt2KelvinMatrix*Voigt*Voigt2KelvinMatrix;
            
        end
        
        function [Voigt] = Kelvin2Voigt(app,Kelvin)
            
            % The Voigt matrix does not preserves the norm of the tensor.
            % Converts Voigt to Kelvin matrix and Kelvin to Voigt matrix
            % The Kelvin elastic matrix preserves the norm of the tensor
            % and its eigen-values and vectors have physical meaning.
            % 
            % All mathematical matrix functions can be applied to Kelvin matrix 
            %
            % References
            % W.K. Thomson (Lord Kelvin),1856 
            %   Elements of a mathematical theory of elasticity, 
            %   Philos. Trans. R. Soc. 146, 481-498.
            % W.K. Thomson (Lord Kelvin),1856 
            %   On six principal strains of an elastic solid, 
            %   Phil. Trans. R. Soc. 166, 495-498.
            % W.K. Thomson (Lord Kelvin),1878 
            %   Mathematical theory of elasticity, 
            %   Encycl. Br. 7, 819-825. 
            %
            % INPUT
            % Kelvin matrix
            % 
            % OUTPUT
            % Voigt matrix
            %
            % David Mainprice (18/07/2018)
            %**************************************************************************
            % Matrix conversion Voigt to Kelvin = inv(Kelvin2Voigt)
            Kelvin2VoigtMatrix = [[  1      0      0     0      0       0];...
                                 [   0      1      0     0      0       0];...
                                 [   0      0      1     0      0       0];...
                                 [   0      0      0   1/sqrt(2)  0     0];...
                                 [   0      0      0     0    1/sqrt(2) 0];...
                                 [   0      0      0     0      0  1/sqrt(2)]];
            % Matrix Kelvin to Voigt method  
            Voigt = Kelvin2VoigtMatrix*Kelvin*Kelvin2VoigtMatrix;
            
        end
        
        function [Csat] = gasman(app,Cdry,Cmin,Bf,pore,Csat)
            
            % Gassmann's (1951) poroelastic relation extended to anisotropic media
            % by R.Brown and J.Korringa (1975) Geophysics vol.40, pp.608-616.
            %
            % Cdry(6,6) = Cij of dry porous media
            % Cmin(6,6) = Cij of non-porous media
            % Bf(6,6)   = Beta of pore fluid (1/Kf)
            % pore      = Porosity (range 0 to 1.0)
            % Csat(6,6) = Cij of saturated media
            
            Sdry   = zeros(6,6); 
            Smin   = zeros(6,6);
            Ssat   = zeros(6,6); 
            Sdry4  = zeros(3,3,3,3);
            Smin4  = zeros(3,3,3,3);
            Ssat4  = zeros(3,3,3,3); 
            Sdiff4 = zeros(3,3,3,3);
            
            Sdry(:,:) = Cdry(:,:);
            Smin(:,:) = Cmin(:,:);
            
            % invert Cij to Sij
            Sdry = inv(Sdry); % GPa 
            Smin = inv(Smin); % GPa
            
            % convert compilance to 4 index
            % Sij -> Sijkl
            [Sdry4,~,~,~] = stiffness(app,Sdry4,Sdry,3);
            [Smin4,~,~,~] = stiffness(app,Smin4,Smin,3);
            
            % beta = 1/K for dry composite and non-porous
            Bdry = 0;
            Bmin = 0;
            
            for alpha = 1:3
                for beta = 1:3
                    Bdry = Bdry + Sdry4(alpha,alpha,beta,beta);
                    Bmin = Bmin + Smin4(alpha,alpha,beta,beta);
                end
            end
            
            % dividing constant
            s = (Bdry-Bmin)+(Bf-Bmin)*pore;
            
            % Sijkl loop
            for i = 1:3
                for j = 1:3
                    % sum over i,j,alpha,alpha
                    Sijdry = 0;
                    Sijmin = 0;
                    for alpha = 1:3
                        Sijdry = Sijdry + Sdry4(i,j,alpha,alpha);
                        Sijmin = Sijmin + Smin4(i,j,alpha,alpha);
                    end
                    for k = 1:3
                        for l = 1:3
                            %  sum over k,l,alpha,alpha
                            Skldry = 0;
                            Sklmin = 0;
                            for alpha = 1:3
                                Skldry = Skldry + Sdry4(k,l,alpha,alpha);
                                Sklmin = Sklmin + Smin4(k,l,alpha,alpha);
                            end
                            % difference Sdryijkl - Satijkl
                            Sdiff4(i,j,k,l) = ((Sijdry-Sijmin)*(Skldry-Sklmin))/s;
                        end
                    end
                end
            end
            
            % Ssat = Sdry - Sdiff
            Ssat4(:,:,:,:) = Sdry4(:,:,:,:) - Sdiff4(:,:,:,:);
            
            
            % convert compilance to 2 index
            % Sijkl -> Sij (Mode = 1)
            [~,Ssat,~,~] = stiffness(app,Ssat4,Ssat,1);
            
            % invert Sij to Cij
            Csat(:,:) = inv(Ssat);
            
        end
        
        function [a4,b2,mode,ierr] = stiffness(app,a4,b2,mode)
            
            % Converts stiffnesses (Cij) and compliances (Sij) between notations
            % a4: Matrix in 4-Subscript notation
            % b2: Matrix in 2-Subscript notation
            % in the four lines below the following information is given
            % type of tensor, number of Subscripts: in -> out
            % 
            % MODE = 1  Compliance  Sijkl -> Sij
            % MODE = 2  Stiffness   Cijkl -> Cij
            % MODE = 3  Compliance    Sij -> Sijkl
            % MODE = 4  Stiffness     Cij -> Cijkl
            % IERR = 16 UNDEFINED MODE
            %
            % ref. K.Helbig 1994 Foundations of Anisotropy for Exploration Seismics
            % Pergamon pp.123-124.
            
            ierr = 0;
            
            % Block off with non-existing code
            if mode < 1 || mode > 4
                ierr = 16;
                return;
            end
            
            % Set output to zero
            if mode <= 2
                b2 = zeros(6,6);
            end
            
            if mode >= 3
                a4 = zeros(3,3,3,3);
            end
            
            % Set subscript exchange and factors
            for i = 1:3
                for j = 1:3
                    if i == j
                        p = i;
                        f1 = 1;
                    else
                        p = 9-i-j;
                        f1 = 2;
                    end
                    for k = 1:3
                        for l = 1:3
                            if k == l
                                q = k;
                                f2 = 1;
                            else
                                q = 9-k-l;
                                f2 = 2;
                            end
                            
                            % Conversion 4 -> 2
                            if mode == 1
                                b2(p,q) = a4(i,j,k,l)*f1*f2; 
                            end
                            if mode == 2
                                b2(p,q) = a4(i,j,k,l); 
                            end
                            % Conversion 2 -> 4    
                            if mode == 3
                                a4(i,j,k,l) = b2(p,q)/f1/f2; 
                            end
                            if mode == 4
                                a4(i,j,k,l) = b2(p,q); 
                                            
                            end
                        end
                    end
                end
            end

        end
        
        function [bulk,shear,VpX,Vs1X,Vs2X,VpY,Vs1Y,Vs2Y,VpZ,Vs1Z,Vs2Z] = xyzv(app,c,drock,ijkl)
            
            % Phase velocities in X,Y,Z directions
            %
            % X = lineation (East) = 010
            % Y = normal to lineation (Origin) = 001
            % Z = foliation pole (North) = 100
            
            xi = zeros(1,3);
            
            xi(1) = 1;
            xi(2) = 0;
            xi(3) = 0;
            
            [V,~] = velo2(app,xi,drock,c,ijkl);
            
            VpZ  = V(1);
            Vs1Z = V(2);
            Vs2Z = V(3);
            
            xi(1) = 0;
            xi(2) = 1;
            xi(3) = 0;
            
            [V,~] = velo2(app,xi,drock,c,ijkl);
            
            VpX  = V(1);
            Vs1X = V(2);
            Vs2X = V(3);
            
            xi(1) = 0;
            xi(2) = 0;
            xi(3) = 1;
            
            [V,~] = velo2(app,xi,drock,c,ijkl);
            
            VpY  = V(1);
            Vs1Y = V(2);
            Vs2Y = V(3);
            
            % Directionally averaged velocities
            Vp = (VpX + VpY + VpZ)/3;
            Vs = (Vs1X + Vs2X + Vs1Y + Vs2Y + Vs1Z + Vs2Z)/6;
            
            % Directionally averaged elastic constants (GPa)
            c11 = drock*Vp^2;
            c44 = drock*Vs^2;
            
            % Isotropic shear and bulk moduli (GPa)
            shear = c44;
            bulk  = c11 - 4*c44/3;
            
        end
        
        function [v,eigvec] = velo2(app,x,rho,c,ijkl)
                
            % Phase-velocity surfaces in an anisotropic medium
            % revised April 1991
            %
            % x(3)        - Direction of interest
            % rho         - Density [g/cm3]
            % v           - Phase velocities (1,2,3 = P,S,SS) [km/s]
            % c           - Elastic stiffness [GPa]
            % eigvec(3,3) - Eigenvectors stored by columns
            
            c = c';
            v      = zeros(1,3);
            t      = zeros(3,3);
            eigvec = zeros(3,3);
            
            % Form symmetric matrix Tik = Cijkl*Xj*Xl
            for i = 1:3
                for k = 1:3
                    t(i,k) = 0;
                    for j = 1:3
                        for l = 1:3
                            m = ijkl(i,j);
                            n = ijkl(k,l);
                            t(i,k) = t(i,k) + c(m,n)*x(j)*x(l);
                        end
                    end
                end
            end
            
            
            % Determine the eigenvalues of symmetric Tij
            [ei,eval] = jacobi(app,t,3,3);
            
            for i = 1:3
                for j = 1:3
                    eigvec(i,j) = ei(j,i);
                end
            end          
            
            % X(I) = wave normal
            % Eigenvalues  = function of wave velocity
            % Eigenvectors = displacement vector
            % polarization plane = contains wave normal and displacement vector
            for i = 1:3
                de = eval(i);      % [GPa]
                dv = sqrt(de/rho); % [km/s]
                v(i) = dv;
            end
            
            % sort velocities into ascending order
            for iop = 1:2
                nop = iop + 1;
                for inop = nop:3
                    if v(inop) <= v(iop)
                        continue;
                    end
                    value   = v(iop);
                    v(iop)  = v(inop);
                    v(inop) = value;
                    for m = 1:3
                        val = eigvec(iop,m);
                        eigvec(iop,m) = eigvec(inop,m);
                        eigvec(inop,m) = val;
                    end
                end
            end
            
            eigvec = eigvec';
            
        end
        
        function [V,D] = jacobi(app,A,N,NP)
            
            % Computes all eigenvalues and vectors of a real symmetric matrix A(i,j)
            % A(N,N), sorted in physical array A(NP,NP)
            % on output the elements of A above the diagonal are destroyed
            % D(NP) eigenvalues of A in its first N elements
            % V(NP,NP) columns contain normalized vectors
            % NROT number of Jacobi rotations
            
            A = A'; 
            nmax = 100;
            D = zeros(1,NP);
            V = zeros(NP,NP);
            b = zeros(1,nmax);
            z = zeros(1,nmax);
            
            
            for ip = 1:N
                for iq = 1:N
                    V(ip,iq) = 0;
                end
                V(ip,ip) = 1;
            end
            
            for ip = 1:N
                b(ip) = A(ip,ip);
                D(ip) = b(ip);
                z(ip) = 0;
            end
            
            nrot = 0;
            
            for i = 1:50
                sm = 0;
                for ip = 1:N-1
                    for iq = ip+1:N
                        sm = sm + abs(A(ip,iq));
                    end
                end
                if sm == 0
                    return;
                end
                if i < 4 
                    tresh = 0.2*sm/N^2;
                else
                    tresh = 0;
                end
                for ip = 1:N-1
                    for iq = ip+1:N
                        G = 100*abs(A(ip,iq));
                        if i > 4 && (abs(D(ip))+G == abs(D(ip))) && (abs(D(iq))+G == abs(D(iq)))
                            A(ip,iq) = 0;
                        elseif abs(A(ip,iq)) > tresh
                            h = D(iq) - D(ip);
                            if abs(h)+G == abs(h)
                                t = A(ip,iq)/h;
                            else
                                theta = 0.5*h/A(ip,iq);
                                t = 1/(abs(theta) + sqrt(1+theta^2));
                                if theta < 0
                                    t = -t;
                                end
                            end
                            c = 1/sqrt(1+t^2);
                            s = t*c;
                            tau = s/(1+c);
                            h = t*A(ip,iq);
                            z(ip) = z(ip) - h;
                            z(iq) = z(iq) + h;
                            D(ip) = D(ip) - h;
                            D(iq) = D(iq) + h;
                            A(ip,iq) = 0;
                            for j = 1:ip-1
                                G = A(j,ip);
                                h = A(j,iq);
                                A(j,ip) = G - s*(h+G*tau);
                                A(j,iq) = h + s*(G-h*tau);
                            end
                            for j = ip+1:iq-1
                                G = A(ip,j);
                                h = A(j,iq);
                                A(ip,j) = G - s*(h+G*tau);
                                A(j,iq) = h + s*(G-h*tau);
                            end
                            for j = iq+1:N
                                G = A(ip,j);
                                h = A(iq,j);
                                A(ip,j) = G - s*(h+G*tau);
                                A(iq,j) = h + s*(G-h*tau);
                            end
                            for j = 1:N
                                G = V(j,ip);
                                h = V(j,iq);
                                V(j,ip) = G - s*(h+G*tau);
                                V(j,iq) = h + s*(G-h*tau);
                            end 
                            nrot = nrot + 1;
                        end
                    end
                end
                
                for ip = 1:N
                    b(ip) = b(ip) + z(ip);
                    D(ip) = b(ip);
                    z(ip) = 0;
                end
            end
            
            V = V';
            
            disp('50 iterations should never happen');
            input('press any key to continue');
            
        end
        
        function [bulk,shear,CVpX,CVs1X,CVs2X,CVpY,CVs1Y,CVs2Y,CVpZ,CVs1Z,CVs2Z] = xyzv3(app,c,drock,ijkl)
            
            % Phase velocities in X,Y,Z directions
            %
            % X = lineation (East) = 010
            % Y = normal to lineation (Origin) = 001
            % Z = foliation pole (North) = 100
            
            xi = zeros(1,3);
            
            xi(1) = 1;
            xi(2) = 0;
            xi(3) = 0;
            
            [V,~] = velo2(app,xi,drock,c,ijkl);
            
            VpZ  = V(1);
            Vs1Z = V(2);
            Vs2Z = V(3);
            
            xi(1) = 0;
            xi(2) = 1;
            xi(3) = 0;
            
            [V,~] = velo2(app,xi,drock,c,ijkl);
            
            VpX  = V(1);
            Vs1X = V(2);
            Vs2X = V(3);
            
            xi(1) = 0;
            xi(2) = 0;
            xi(3) = 1;
            
            [V,~] = velo2(app,xi,drock,c,ijkl);
            
            VpY  = V(1);
            Vs1Y = V(2);
            Vs2Y = V(3);
            
            % Directionally averaged velocities
            Vp = (VpX + VpY + VpZ)/3;
            Vs = (Vs1X + Vs2X + Vs1Y + Vs2Y + Vs1Z + Vs2Z)/6;
            
            % Directionally averaged elastic constants (GPa)
            c11 = drock*Vp^2;
            c44 = drock*Vs^2;
            
            % Isotropic shear and bulk moduli (GPa)
            shear = c44;
            bulk  = c11 - 4*c44/3;
            
            % Seismic elastic moduli (GPa) in directions X,Y,Z
            CVpX  = drock*VpX^2;
            CVs1X = drock*Vs1X^2;
            CVs2X = drock*Vs2X^2;
            CVpY  = drock*VpY^2;
            CVs1Y = drock*Vs1Y^2;
            CVs2Y = drock*Vs2Y^2;
            CVpZ  = drock*VpZ^2;
            CVs1Z = drock*Vs1Z^2;
            CVs2Z = drock*Vs2Z^2;
            
        end
        
        function [fVpX,fVpY,fVpZ] = fmax3(app,CVpX,CVpY,CVpZ,visc,Vpf,rhof)
            
            % CVpX,CVpY,CVpZ - Seismic elastic moduli (GPa) in directions X,Y,Z
            % visc - Viscosity of fluid (Pa.s)
            % Vpf  - Vp of fluid (km/s)
            % rhof - Fluid density (g/cm3)
            % fVpX,fVpY,fVpZ - fmax (1/s) associated with VpX,VpY,VpZ 
            
            % Seismic Vp -> elastic moduli of fluid in GPa
            CVpf = rhof*Vpf^2;
            
            % Seismic moduli in GPa -> Pa
            Xmodd = CVpX*10^9;
            Ymodd = CVpY*10^9;
            Zmodd = CVpZ*10^9;
            Fmodd = CVpf*10^9;
            
            % fmax
            fVpX = sqrt(Fmodd*(Fmodd+Xmodd))/visc;
            fVpY = sqrt(Fmodd*(Fmodd+Ymodd))/visc;
            fVpZ = sqrt(Fmodd*(Fmodd+Zmodd))/visc;
            
        end
        
        function [Qp,Qs1,Qs2] = qinv(app,rho,VpL,Vs1L,Vs2L,VpH,Vs1H,Vs2H)
            
            % rho           - Density (g/cm3)
            % VpL,Vs1L,Vs2L - Low frequency velocities [km/s]
            % VpH,Vs1H,Vs2H - High frequency velocities [km/s]
            % Qp,Qs1,Qs2    - 1/Qmax
            
            % Elastic constants (GPa) associated with Vp,Vs1 and Vs2
            c11l  = rho*VpL^2;
            c44l1 = rho*Vs1L^2;
            c44l2 = rho*Vs2L^2;
            c11h  = rho*VpH^2;
            c44h1 = rho*Vs1H^2;
            c44h2 = rho*Vs2H^2;
            
            % 1/Qp max
            Qp = (c11h-c11l)/(2*sqrt(c11h*c11l));
            
            % 1/Qs max
            Qs1 = (c44h1-c44l1)/(2*sqrt(c44h1*c44l1));
            Qs2 = (c44h2-c44l2)/(2*sqrt(c44h2*c44l2));
            
        end
        
        function [v,eigvec,vg1,vg2,vg3] = ...
                            vpfile(app,c,drock,ihemi,icont,outputDir,title,textvf,ijkl)
            
            sn1 = zeros(1,3); 
            sn2 = zeros(1,3); 
            sn3 = zeros(1,3); 
            pm1 = zeros(1,3); 
            pm2 = zeros(1,3); 
            pm3 = zeros(1,3); 
            c4  = zeros(3,3,3,3); 
           
            
            %*****************************************************************
            %     Convert from Cij to Cijkl
            %*****************************************************************
            
            [c4,~,~,~] = stiffness(app,c4,c,4);
            
            %*****************************************************************
            %     PLANE WAVE CALCULATION OVER WHOLE GEOGRAPHIC HEMISPHERE    *
            %     USING A GRID SPACING OF 6 DEGREES                          *
            %*****************************************************************
            % FILE=Vfile
            vfile = [outputDir,deblank(title),'_Clow',deblank(textvf),'_VpG.txt'];
           
            fid_30 = fopen(vfile,'w+');
            fprintf(fid_30,'%s \n',[deblank(title),'_Clow',deblank(textvf),'_VpG.txt']);
            
            for i = 1:16
                xinc = icont*(90-((i-1)*6));
                for j = 1:61
                    az = 90*(j-1)/15;
                    
                    % Convert geographic coordinates to direction cosines
                    [xi] = cart(app,xinc,az,ihemi);
                            
                    % Calculate phase velocities 
                    [v,eigvec] = velo2(app,xi,drock,c,ijkl);
                    
                    % Particle motion vectors for qP,qVS1,qVS2
                    for k = 1:3
                        pm1(k) = eigvec(1,k);
                        pm2(k) = eigvec(2,k);
                        pm3(k) = eigvec(3,k);
                    end
                    
                    % Slowness vectors SN1,2,3 in s/Km
                    snm1 = 1/v(1);
                    snm2 = 1/v(2);
                    snm3 = 1/v(3);
                    for k = 1:3
                        sn1(k) = snm1*xi(k);
                        sn2(k) = snm2*xi(k);
                        sn3(k) = snm3*xi(k);
                    end
                    
                    % Ray-velocity vector corresponding to the slowness vectors SN1,2,3
                    [vg1] = rayvel(app,c4,sn1,drock);
                    [vg2] = rayvel(app,c4,sn2,drock);
                    [vg3] = rayvel(app,c4,sn3,drock);
                    
                    % Magnitude of group velocity
                    vg1m = sqrt(vg1(1)^2 + vg1(2)^2 + vg1(3)^2);
                    vg2m = sqrt(vg2(1)^2 + vg2(2)^2 + vg2(3)^2);
                    vg3m = sqrt(vg3(1)^2 + vg3(2)^2 + vg3(3)^2);
                    
                    % WRITE TO FILE 15
                    % Phase velocities qVp,qVS1,qVS2
                    fprintf(fid_30,[repmat(' ',1,1),repmat('%12.4f',1,9),' \n'],...
                                    v(1),v(2),v(3));
                    
                    % Particle motion vectors for qVp,qVS1,qVS2
                    % XP,XS1,XS2,YP,YS1,YS2,ZP,ZS1,ZS2
                    fprintf(fid_30,[repmat(' ',1,1),repmat('%12.4f',1,9),' \n'],...
                                    eigvec(1,1),eigvec(2,1),eigvec(3,1),...
                                    eigvec(1,2),eigvec(2,2),eigvec(3,2),...
                                    eigvec(1,3),eigvec(2,3),eigvec(3,3));
                    
                    % Group velocity vectors for qVp,qVS1,qVS2
                    fprintf(fid_30,[repmat(' ',1,1),repmat('%12.4f',1,9),' \n'],...
                                    vg1(1),vg1(2),vg1(3),...
                                    vg2(1),vg2(2),vg2(3),...
                                    vg3(1),vg3(2),vg3(3));
                             
                end
            end
            %******************************************************************
            %     END LOOP
            %******************************************************************
            
            % CLOSE OUTPUT FILE 30 Vp file
            fclose(fid_30);
            
        end
        
        function [x] = cart(app,xinc,azm,ihemi)
                
            % Convert from spherical to cartesian coordinates
            %
            % NORTH X=100  WEST Y=010 UP Z=001 DOWN -Z=00-1
            % IHEMI=-1 LOWER HEMISPHERE +VE DIP
            % IHEMI=+1 UPPER HEMISPHERE +VE DIP
            
            x = zeros(1,3);
            
            rad  = pi/180;
            caz  = cos(azm*rad);
            saz  = sin(azm*rad);
            rinc = ihemi*xinc;
            cinc = cos(rinc*rad);
            sinc = sin(rinc*rad);
            x(1,1) = caz*cinc;
            x(1,2) = -saz*cinc;
            x(1,3) = sinc;
            
            % Normalize to direction cosines
            r = sqrt(x(1,1)*x(1,1) + x(1,2)*x(1,2) + x(1,3)*x(1,3));
            
            x(:,:) = x(:,:)/r;
            
        end
        
        function [vg] = rayvel(app,c4,sn,rho)
                
            % Calculate the ray-velocity vector corresponding to a slowness vector
            %
            % c4        - Stiffness tensor
            % sn        - Slowness vector
            % vg        - Ray velocity vector
            % f(i,k)    - Components of determinant in terms of components of 
            %             the slowness vector
            % df(i,j,k) - Array of derivatives of f with respect to the components of 
            %             the slowness vector
            % dfd(i)    - Gradient of the function f
            
            f   = zeros(3,3);
            cf  = zeros(3,3); 
            df  = zeros(3,3,3); 
            dfd = zeros(1,3); 
            vg  = zeros(1,3); 
            
            for i = 1:3
                for k = 1:3
                    f(i,k) = 0;
                    for j = 1:3
                        for l = 1:3
                            % EQN. 4.23
                            f(i,k) = f(i,k) + c4(i,j,k,l)*sn(j)*sn(l);
                        end
                    end
                    if i == k
                        f(i,k) = f(i,k) - rho; 
                    end
                end
            end
            
            % Signed cofactors of f(i,k)
            cf(1,1) = f(2,2)*f(3,3) - f(2,3)^2;
            cf(2,2) = f(1,1)*f(3,3) - f(1,3)^2;
            cf(3,3) = f(1,1)*f(2,2) - f(1,2)^2;
            cf(1,2) = f(2,3)*f(3,1) - f(2,1)*f(3,3);
            cf(2,1) = cf(1,2);
            cf(2,3) = f(3,1)*f(1,2) - f(3,2)*f(1,1);
            cf(3,2) = cf(2,3);
            cf(3,1) = f(1,2)*f(2,3) - f(1,3)*f(2,2);
            cf(1,3) = cf(3,1);
            
            % Derivatives of determinant elements 
            for i = 1:3
                for j = 1:3
                    for k = 1:3
                        df(i,j,k) = 0;
                        for l = 1:3
                            df(i,j,k) = df(i,j,k) + (c4(i,j,k,l) + c4(k,j,i,l))*sn(l);
                        end
                    end
                end
            end
            
            % Components of gradient
            for k = 1:3
                dfd(1,k) = 0;
                for i = 1:3
                    for j = 1:3
                        dfd(1,k) = dfd(1,k) + df(i,j,k)*cf(i,j);
                    end
                end
            end
            
            % Normalize to obtain group velocity vg(i)
            denom = 0;
            for i = 1:3
                denom = denom + sn(i)*dfd(1,i);
            end
            
            vg(:,:) = dfd(:,:)/denom;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % Add current directory to path
            currentDir = pwd;
            addpath(genpath(currentDir));
            
            
            % Set up default
            
            % Effective Medium =====================================
            
            % Two-component system of A and B for DEM calculation
            app.HostmediumNameEditField.Value = 'ModelSC3';
            app.HostmediumElasticConstantsEditField.Value = 'Morales2018_ModelSC3_GPa.txt';
            app.HostmediumDensityEditField.Value = 2.7292;
            app.InclusionNameEditField.Value = 'Basalt';
            app.InclusionElasticConstantsEditField.Value = 'Basalt1200C_GPa.txt';
            app.InclusionDensityEditField.Value = 2.70; 
            app.InputfilesdirectoryEditField.Value = '';
            app.GassDemdirectoryEditField.Value = '';
               
            % Labels
            app.Densitygcm3Label.Text = ['Density (g/cm',char(179),')']; 
            app.FluidDensitygcm3EditFieldLabel.Text = ['g/cm',char(179)];
            app.FluidViscosityPasEditFieldLabel.Text = ['Pa',char(183),'s'];
            app.FluidViscosityEGLabel.Text = {['e.g. Water = 1.0E-03 Pa',char(183),'s']; ['       Basalt = 10 Pa',char(183),'s']};
            app.FluidCompressibilityEGLabel.Text = {'e.g. Water = 0.4878 1/GPa'; ['       Basalt = 0.0546 1/GPa at 1233',char(176),'C']};
            app.FluidVpEGLabel.Text = {['e.g. Water = 1.480 km/s at 20',char(176),'C']; ['       Basalt = 2.610 km/s at 1233',char(176),'C']};
            app.FluidDensityEGLabel.Text = {['e.g. Water = 1.000 g/cm',char(179)]; ['       Basalt = 2.680 g/cm',char(179),' at 1233',char(176),'C']};
            app.SPOLabel.Text = {'(for fluid inclusion)'; ''; 'User defined shape ellipsoid orientation'; ''; ['A1 should be 90',char(176),' to A3']};
            app.A1AzimuthEditFieldLabel.Text = ['Azimuth (',char(176),')'];
            app.A1InclinationEditFieldLabel.Text = ['Inclination (',char(176),')'];
            app.Check1AngleA1toA3EditFieldLabel.Text = [' Check 1: Angle (',char(176),') A1 to A3 ='];
            app.Check2AngleA1toA3EditFieldLabel.Text = [' Check 2: Angle (',char(176),') A1 to A3 = '];
            app.HelpOutputsNoteLabel.Text = {'Note: '; ['- Elastic stiffness tensor C (Voigt notation in GPa); Density (g/cm',char(179),'); Velocity (km/s).']; '- VpXH, VpYH and VpZH are high frequency Vp in X, Y and Z directions, respectively.'; '- VpXL, VpYL and VpZL are low frequency Vp in X, Y, and Z directions, respectively.'; '- Vs1XH, Vs1XH and Vs1XH are high frequency fast Vs in X, Y and Z directions, respectively.'; '- Vs1XL, Vs1XL and Vs1XL are low frequency fast Vs in X, Y, and Z directions, respectively.'; '- Vs2XH, Vs2XH and Vs2XH are high frequency slow Vs in X, Y and Z directions, respectively.'; '- Vs2XL, Vs2XL and Vs2XL are low frequency slow Vs in X, Y, and Z directions, respectively.'; ''; ''; ''};


            % Fluid Properties
            app.CompressibilityBfEditField.Value = 0.0671;
            app.ViscosityEditField.Value = 10;
            app.VpEditField.Value = 2.35;
            app.DensityEditField.Value = 2.70;
            
            % Max. % error for Green's Tensor
            app.MaxerrorforGreensTensoreg01EditField.Value = 0.1;
            
            
            % Inclusion Shape and Orientation ======================
            
            % Ellipsoid semi-axes lengths A1:A2:A3
            app.Sphere111Button.Value = false;
            app.EllipsoidButton.Value = true;
            app.EllipsoidA1EditField.Value = 5;
            app.EllipsoidA2EditField.Value = 5;
            app.EllipsoidA3EditField.Value = 1;
            
            % Orientation of shape ellipsoid semi-axes
            app.ParalleltoelastictensoraxesButton.Value = false;
            app.ParalleltouserdefinedorientationSPOButton.Value = true;
            app.A1AzimuthdegreeEditField.Value = 90;
            app.A1InclinationdegreeEditField.Value = 0;
            app.A3AzimuthdegreeEditField.Value = 0;
            app.A3InclinationdegreeEditField.Value = 0;
            
            
            % polar figure using plot (R2016b-R2018a)
            radius = 1;
            theta = (0:(pi/180):2*pi);
            x = radius*cos(theta);
            y = radius*sin(theta);
            
            plot(app.Parallel2XUIAxes,x,y,'k-');
            hold(app.Parallel2XUIAxes,'on');
            
            % X1 axis            
            plot(app.Parallel2XUIAxes,[0 0],[0.2 radius],'k--');
            hold(app.Parallel2XUIAxes,'on');
            plot(app.Parallel2XUIAxes,[0 0],[-0.2 -radius],'k--');
            hold(app.Parallel2XUIAxes,'on');
            plot(app.Parallel2XUIAxes,[0 0],[radius -radius],'Marker','o','MarkerSize',5,'MarkerFaceColor',[0.94,0.94,0.94],'MarkerEdgeColor','b','LineStyle','none');
            hold(app.Parallel2XUIAxes,'on');
            
            % X2 axes 
            plot(app.Parallel2XUIAxes,[0.4 radius],[0 0],'k--');
            hold(app.Parallel2XUIAxes,'on');
            plot(app.Parallel2XUIAxes,[-radius -0.4],[0 0],'k--');
            hold(app.Parallel2XUIAxes,'on');
            plot(app.Parallel2XUIAxes,[radius -radius],[0 0],'Marker','o','MarkerSize',5,'MarkerFaceColor',[0.94,0.94,0.94],'MarkerEdgeColor','b','LineStyle','none');
            hold(app.Parallel2XUIAxes,'on');
            
            % add text for axes
            txN = text(app.Parallel2XUIAxes,'String','North = 0','Position',[-0.4,(radius+0.2)]);
            txS = text(app.Parallel2XUIAxes,'String','South = 180','Position',[-0.5,(-radius-0.2)]);
            txE = text(app.Parallel2XUIAxes,'String','East = 90','Position',[(radius+0.2),0]);
            txW = text(app.Parallel2XUIAxes,'String','West = 270','Position',[(-radius-1.1), 0]);
            
            txX1 = text(app.Parallel2XUIAxes,'String','X1//A1','Position',[-0.26,(radius+0.4)]);
            txX2 = text(app.Parallel2XUIAxes,'String','X2//A2','Position',[(-radius-0.9),-0.25]);
            txX3 = text(app.Parallel2XUIAxes,'String','X3//A3','Position',[-0.27,0],'BackgroundColor',[1 1 1]);
            
            % axis properties           
            app.Parallel2XUIAxes.Visible = 'on';
            app.Parallel2XUIAxes.Position = [1 10 300 300]; % [x y width height]
            app.Parallel2XUIAxes.Color = [0.94 0.94 0.94];
            app.Parallel2XUIAxes.XColor = [0.94 0.94 0.94];
            app.Parallel2XUIAxes.YColor = [0.94 0.94 0.94];           
            app.Parallel2XUIAxes.Title.String = '';
            app.Parallel2XUIAxes.XLabel.String = '';
            app.Parallel2XUIAxes.YLabel.String = '';           
            app.Parallel2XUIAxes.XLim = [-2 2];
            app.Parallel2XUIAxes.YLim = [-2 2];


            % DEM analysis  ====================================
            
            % File name and directory for DEM results
            app.FilenameEditField.Value = 'GassDem_ModelSC3_Melt_AspectRatio5';
            app.DirectoryEditField.Value = '';
            app.Maxvolumefractionofinclusion01EditField.Value = 0.2;
            
            % Qmax^(-1)
            app.QmaxInvEditFieldLabel.Text = '1/Qmax';
            app.Note1QmaxisplottedassemilogscaleLabel.Text = '(Note: 1/Qmax is plotted as semi-log scale)';
            
            % Vp axis properties           
            app.DEMresultsVpUIAxes.Visible = 'on';            
            app.DEMresultsVpUIAxes.Position = [480,340,400,280]; % [x y width height]
            app.DEMresultsVpUIAxes.Box = 'on';
            app.DEMresultsVpUIAxes.Title.String = '';
            app.DEMresultsVpUIAxes.XLabel.String = 'X'; 
     
            % Vs axis properties           
            app.DEMresultsVsUIAxes.Visible = 'on';                     
            app.DEMresultsVsUIAxes.Position = [480,40,400,280]; % [x y width height]
            app.DEMresultsVsUIAxes.Box = 'on';
            app.DEMresultsVsUIAxes.Title.String = ''; 
            app.DEMresultsVsUIAxes.XLabel.String = 'X'; 


            % Help-Outputs =====================================
            
            app.OutputFilesDescriptionUITable.Data{1,1} = '*_GDem_Print.txt';
            app.OutputFilesDescriptionUITable.Data{2,1} = '*_GDem_CijH.txt';
            app.OutputFilesDescriptionUITable.Data{3,1} = '*_GDem_CijL.txt';
            app.OutputFilesDescriptionUITable.Data{4,1} = '*_GDem_VpH.txt';
            app.OutputFilesDescriptionUITable.Data{5,1} = '*_GDem_VpL.txt';
            app.OutputFilesDescriptionUITable.Data{6,1} = '*-Clow1.txt';
            app.OutputFilesDescriptionUITable.Data{7,1} = '*-Clow99.txt';
            app.OutputFilesDescriptionUITable.Data{8,1} = '*_Clow1_VpG.txt';
            app.OutputFilesDescriptionUITable.Data{9,1} = '*_Clow99_VpG.txt';
            app.OutputFilesDescriptionUITable.Data{1,2} = 'Elastic stiffness tensor and density of host medium and inclusion, and fixed orientation of shape ellipsoid axes';
            app.OutputFilesDescriptionUITable.Data{2,2} = 'High frequency elastic stiffness components by classical DEM solid-liquid medium';
            app.OutputFilesDescriptionUITable.Data{3,2} = 'Low frequency elastic stiffness components by classical DEM solid-empty pore medium and Gassmann''s (1951) poroelastic relationship';
            app.OutputFilesDescriptionUITable.Data{4,2} = 'High frequency seismic velocity by classical DEM solid-liquid medium';
            app.OutputFilesDescriptionUITable.Data{5,2} = 'Low frequency seismic velocity by classical DEM solid-empty pore medium and Gassmann''s (1951) poroelastic relationship';
            app.OutputFilesDescriptionUITable.Data{6,2} = 'Low frequency elastic stiffness tensor at fluid volumn fraction 0.01';
            app.OutputFilesDescriptionUITable.Data{7,2} = 'Low frequency elastic stiffness tensor at fluid volumn fraction 0.99';
            app.OutputFilesDescriptionUITable.Data{8,2} = 'Low frequency phase velocity, particle motion and group velocity vectors over geographic hemisphere at fluid volumn fraction 0.01';
            app.OutputFilesDescriptionUITable.Data{9,2} = 'Low frequency phase velocity, particle motion and group velocity vectors over geographic hemisphere at fluid volumn fraction 0.99';


        end

        % Button pushed function: CheckButton
        function CheckButtonPushedFcn(app, event)
               
            al = app.A1AzimuthdegreeEditField.Value;
            xl = app.A1InclinationdegreeEditField.Value;
            af = app.A3AzimuthdegreeEditField.Value;
            xf = app.A3InclinationdegreeEditField.Value;
            
            ihemi = 1;
            
            [re,az1,xinc1,az2,xinc2,az3,xinc3] = orientation(app,al,xl,af,xf,ihemi);
            
            % re: Shape ellipsoid rotation matrix
            app.ShapeEllipsoidRotationMatrix11EditField.Value = re(1,1);
            app.ShapeEllipsoidRotationMatrix21EditField.Value = re(2,1);
            app.ShapeEllipsoidRotationMatrix31EditField.Value = re(3,1);
            app.ShapeEllipsoidRotationMatrix12EditField.Value = re(1,2);
            app.ShapeEllipsoidRotationMatrix22EditField.Value = re(2,2);
            app.ShapeEllipsoidRotationMatrix32EditField.Value = re(3,2);
            app.ShapeEllipsoidRotationMatrix13EditField.Value = re(1,3);
            app.ShapeEllipsoidRotationMatrix23EditField.Value = re(2,3);
            app.ShapeEllipsoidRotationMatrix33EditField.Value = re(3,3);
            
            app.A1AZEditField.Value = az1;
            app.A1INCEditField.Value = xinc1;
            app.A2AZEditField.Value = az2;
            app.A2INCEditField.Value = xinc2;
            app.A3AZEditField.Value = az3;
            app.A3INCEditField.Value = xinc3;


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushedFcn(app, event)
            
            % Preallocation                                                      
            
            eaxis  = zeros(1,3); 
            RBACKI = zeros(3,3); 
            %C      = zeros(6,6);
            EC     = zeros(6,6);
            Cdem1  = zeros(6,6);
            Cdem2  = zeros(6,6); 
            CG     = zeros(6,6); 
            Cpore  = zeros(6,6); 
            Csolid = zeros(6,6); 
            Cdry   = zeros(6,6); 
            Csat   = zeros(6,6); 
            Chigh  = zeros(6,6); 
            Clow   = zeros(6,6); 
            
            NMIN    = 2; % Two phases (including host and inclusion)
            mineral = cell(NMIN,1);
            efile   = cell(NMIN,1);
            rho     = zeros(NMIN,1); 
            axis    = zeros(NMIN,3);
            ioptel  = zeros(NMIN,1);
            ioptoe  = zeros(NMIN,1);
            ECM     = zeros(NMIN,6,6); 
            REMIN   = zeros(NMIN,3,3); 
            
            ijkl = reshape([1,6,5,6,2,4,5,4,3],[3,3]);
            l1   = reshape([1,2,3,2,3,1],[1,6]);
            l2   = reshape([1,2,3,3,1,2],[1,6]);
            
            column  = cell(1,22);
            column2 = cell(1,23);
            column3 = cell(1,23);
            
            column(1,:) = {'Vb',...
                           'C11','C12','C13','C14','C15','C16',...
                           'C22','C23','C24','C25','C26',...
                           'C33','C34','C35','C36',...
                           'C44','C45','C46',... 
                           'C55','C56',... 
                           'C66'}; 
            column2(1,:) = {'Vb(%)','Density',...
                            'VpXL','Vs1XL','Vs2XL',...
                            'VpYL','Vs1YL','Vs2YL',...
                            'VpZL','Vs1ZL','Vs2ZL',...
                            'Q VpX','Q Vs1X','Q Vs2X',...
                            'Q VpY','Q Vs1Y','Q Vs2Y',...
                            'Q VpZ','Q Vs1Z','Q Vs2Z',...
                            'fmax-X','fmax-Y','fmax-Z'};
            column3(1,:) = {'Vb(%)','Density',...
                            'VpXH','Vs1XH','Vs2XH',...
                            'VpYH','Vs1YH','Vs2YH',...
                            'VpZH','Vs1ZH','Vs2ZH',...
                            'Q VpX','Q Vs1X','Q Vs2X',...
                            'Q VpY','Q Vs1Y','Q Vs2Y',...
                            'Q VpZ','Q Vs1Z','Q Vs2Z',...
                            'fmax-X','fmax-Y','fmax-Z'};
            
            
            %**************************************************************************
            %                        INPUT SECTION (user defined)                     *
            %**************************************************************************
            
            % Output file name
            title = app.FilenameEditField.Value;            
            outputDir = app.DirectoryEditField.Value;
            
            % Max. %error for Greens Tensor *(e.g. 0.1)*
            errmax = app.MaxerrorforGreensTensoreg01EditField.Value; 
            
            % Fluid compressibility Bf
            % Bf = 1/Kf (1/GPa), Kf: Bulk modulus
            % e.g. Water  = 0.4878 1/GPa
            %      Basalt = 0.0546 1/GPa at 1233C
            bf = app.CompressibilityBfEditField.Value;
            
            % Fluid viscosity (Pa.s) 
            % e.g. Water  = 1.0E-03 Pa.s 
            %      Basalt = 10 Pa.s 
            visc = app.ViscosityEditField.Value;
            
            % Fluid Vp (km/s) 
            % e.g. Water  = 1.480 km/s at 20C 
            %      Basalt = 2.610 km/s at 1233C 
            vpf = app.VpEditField.Value;
            
            % Fluid density (g/cm3)
            % e.g. Water  = 1.000 g/cm3
            %      Basalt = 2.680 g/cm3 at 1233C
            rhof = app.DensityEditField.Value;
            
            % Name of phase
            mineral{1,1} = app.HostmediumNameEditField.Value;
            mineral{2,1} = app.InclusionNameEditField.Value;
            
            % Elastic Constant Data (GPa) for phases A and B
            efile{1,1} = app.HostmediumElasticConstantsEditField.Value;
            efile{2,1} = app.InclusionElasticConstantsEditField.Value;
            
            % Density (g/cm3)f
            rho(1,1) = app.HostmediumDensityEditField.Value;
            rho(2,1) = app.InclusionDensityEditField.Value;
            
            % Ellipsoid inclusion semi-axes A1,A2,A3
            % Same for all grains (or fluid) of this phase
            % 1 = Sphere
            % 2 = Ellipsoid
            
            % Host medium
            ioptel(1,1) = 1;
            ioptoe(1,1) = 1;
            % Inclusion
            if app.Sphere111Button.Value == true
                ioptel(2,1) = 1;
            elseif app.EllipsoidButton.Value == true
                ioptel(2,1) = 2;
            end
                        
            
            % Orientation of shape ellipsoid semi-axes');
            % 1 = parallel to elastic axes of each crystal');
            %     ---- or  \\\\\  or IIIII or  //// etc   ');
            %     ---- or  \\\\\  or IIIII or  //// etc   ');
            % 2 = parallel to user-defined orientation (SPO)');
            %    (type 2 for fluid inclusion)');
            
            if app.ParalleltoelastictensoraxesButton.Value == true
                ioptoe(2,1) = 1;
            elseif app.ParalleltouserdefinedorientationSPOButton.Value == true
                ioptoe(2,1) = 2;
            end                   
                                  
            
            % Ellipsoid Semi-axes lengths: [A1,A2,A3]           
            axis(1,:) = 1; % sphere 1:1:1
            axis(2,1) = app.EllipsoidA1EditField.Value; % ellipsoid A1
            axis(2,2) = app.EllipsoidA2EditField.Value; % ellipsoid A2
            axis(2,3) = app.EllipsoidA3EditField.Value; % ellipsoid A3
            
            % User defined shape ellipsoid orientation
            % A1 should be 90 degrees to A3
            %                              
            % Azimuth (degree) of ellipsoid semi-axis A1            
            al = app.A1AzimuthdegreeEditField.Value;
            
            % Inclination (degree) of ellipsoid semi-axis A1
            xl = app.A1InclinationdegreeEditField.Value;
            
            % Azimuth (degree) of ellipsoid semi-axis A3
            af = app.A3AzimuthdegreeEditField.Value;
            
            % Inclination (degree) of ellipsoid semi-axis A3
            xf = app.A3InclinationdegreeEditField.Value;
                    
            % Which phase is the inclusion (1 or 2)? 
            idem = 2;
            
            % Max. volume Fraction of fluid (0-1)
            vliq = app.Maxvolumefractionofinclusion01EditField.Value;
            
            %**************************************************************************
            %                   END OF INPUT SECTION (user defined)                   *
            %**************************************************************************
            
            
            % Create text files to save results
            pfile  = [outputDir,deblank(title),'_GDem_Print.txt'];
            fid_12 = fopen(pfile,'w+');
            
            % Create text file to save velocities for low frequency 
            cfile1 = [outputDir,deblank(title),'_GDem_VpL.txt'];
            fid_16 = fopen(cfile1,'w+');
            for i = 1:length(column2)
                fprintf(fid_16,'%8s \t',cell2mat(column2(i))); 
            end
            fprintf(fid_16,' \n');
            
            % Create text file to save velocities for high frequency 
            cfile2 = [outputDir,deblank(title),'_GDem_VpH.txt'];
            fid_17 = fopen(cfile2,'w+');
            for i = 1:length(column3)
                fprintf(fid_17,'%8s \t',cell2mat(column3(i))); 
            end
            fprintf(fid_17,' \n');
            
            % Create text file to save Cij for low frequency 
            cfile3 = [outputDir,deblank(title),'_GDem_CijL.txt'];
            fid_18 = fopen(cfile3,'w+');
            for i = 1:length(column)
                fprintf(fid_18,'%8s \t',cell2mat(column(i))); 
            end
            fprintf(fid_18,' \n');
            
            % Create text file to save Cij for high frequency 
            cfile4 = [outputDir,deblank(title),'_GDem_CijH.txt'];
            fid_19 = fopen(cfile4,'w+');
            for i = 1:length(column)
                fprintf(fid_19,'%8s \t',cell2mat(column(i))); 
            end
            fprintf(fid_19,' \n');           
            
            % Hemisphere Convention:
            %    +1 = dip +VE upper hemisphere
            %    -1 = dip +VE lower hemisphere
            ihemi = 1;
            
            % Hemisphere Convention For Contouring:
            %    +1 = dip +VE hemisphere
            %    -1 = dip -VE hemisphere
            icont = 1;
            
            fprintf(fid_12,['\n  Seismic Anisotropy Analysis', ...
                            repmat(' ',1,8),'%s \n\n'],title);
            
            %**************************************************************************
            %                        MINERAL LOOP                                     *                    
            %**************************************************************************
            
            
            for MIN = 1:NMIN
                    
                % Elastic Constant Data (GPa) for phase 1 or 2
                fid_10 = fopen(efile{MIN,1},'r+');  
            
                % Read elastic constants from fid_10
                xref = cell(2,1);
                xref(1,1) = cellstr(fgetl(fid_10));
                xref(2,1) = cellstr(fgetl(fid_10));
                a_temp  = fgetl(fid_10);
                a_temp2 = textscan(a_temp,'%8.4f');
                a = a_temp2{1,1}(1:3);
                e = a_temp2{1,1}(4:6);
                indexx = a_temp2{1,1}(end);   
                
                EC = zeros(6,6);
                
                for i = 1:6        
                    ec_temp  = fgetl(fid_10);
                    ec_temp2 = textscan(ec_temp,'%8.4f');
                    EC(i,:)  = ec_temp2{1,1}(:); % GPa         
                end
                
                ECM(MIN,:,:) = EC(:,:);
                
                fclose(fid_10);
            
                % Ellipsoid inclusion semi-axes A1,A2,A3
                % Same for all grains (or fluid) of this phase
                % 1 = Sphere
                % 2 = Ellipsoid
                %
                % Sphere 1:1:1
                % axes all equal one and rotation matrix equal unitary matrix
                if ioptel(MIN,1) == 1 
                    axis(MIN,1) = 1;
                    axis(MIN,2) = 1;
                    axis(MIN,3) = 1;        
                    ioptoe(MIN,1) = 1;                   
                    for i = 1:3
                       REMIN(MIN,i,i) = 1; 
                    end
                            
                % Ellipsoid Semi-axes lengths: [A1,A2,A3]   
                elseif ioptel(MIN,1) == 2        
                    switch ioptoe(MIN,1)
                        case 1 
                            for i = 1:3
                                REMIN(MIN,i,i) = 1;
                            end
                        case 2
                            % Orientation
                            [re,az1,xinc1,az2,xinc2,az3,xinc3] = orientation(app,al,xl,af,xf,ihemi);
            
                            REMIN(MIN,:,:) = re(:,:);                           
                    end
                end 
                    
                %**********************************************************************
                %                      PRINT ELASTIC CONSTANTS                        *
                %**********************************************************************
            
                fprintf(fid_12,['\n  Single Crystal Elastic Stiffness Matrix \n',...
                                '    %s \n    %s \n\n'],cell2mat(xref(1,1)),cell2mat(xref(2,1)));
            
                for i = 1:6
                    fprintf(fid_12,[repmat('%9.2f',1,6),' \n\n'],EC(i,1:6)); 
                end
            
                fprintf(fid_12,'  Density = %7.4f  g/cm3 \n',(rho(MIN,1)));
                fprintf(fid_12,['  No. of Medium: %3i   %s \n',...
                                '  Ellipsoid semi-axes lengths A1:A2:A3 = %4i:%4i:%4i \n'], ...
                                MIN,cell2mat(mineral(MIN)),axis(MIN,1),axis(MIN,2),axis(MIN,3));
            
                if ioptoe(MIN) == 2 
                    fprintf(fid_12,['  Fixed orientation of shape ellipsoid axes: \n',...
                                    '     A1: AZ = %6.2f INC = %6.2f \n',...
                                    '     A2: AZ = %6.2f INC = %6.2f \n',...
                                    '     A3: AZ = %6.2f INC = %6.2f \n\n\n'],...
                                    az1,xinc1,az2,xinc2,az3,xinc3);
                end
            end
            
            %**************************************************************************
            %                       END OF MINERAL LOOP                               *
            %**************************************************************************


            % *************************************************************************
            %  DEM LOOP
            %  with TWO DEM determinations
            %  a) The high frequency elatic constants 
            %    (classical DEM solid-liquid)
            %  b) The low frequency elatic constants
            %    (classical DEM solid-empty pore plus Gassmann poro-elastic analysis)
            % *************************************************************************
            % 
            % The self-consistent average is often close to the VRH (or Hill) average. 
            % It is considered a reliable estimate of the 'true' elastic constants
            %    of anisotropic rock comprising of several mineral phases
            %    of contrasting elastic constants (i.e. elastically heterogeneous).
            % In this program the SC polycrystalline polyphase average elastic
            %    constants are used for 1OO percent solid (aka background medium)
            %    at pole A of the Differential Effective Medium (DEM). 
            %    Pole B is the 1OO percent fluid (aka inclusion).
            % The self-consistent scheme is an interative method which requires
            %    an initial value, the Voigt (upper bound) average is used as the
            %    initial value of Cij and the convergence of the SC method always
            %    results in a final SC value that is less than the Voigt average.
            %   
            %   SC = I * Csolid
            %       I   *
            %       I  +   *
            %       I    +    *
            %       I       +      *
            %       I           +        *
            %       I                +           *  high frequency
            %       I                     +
            %       I                           +  low frequency
            %       I
            %       I
            %       ________________________________________
            %       A 100% solid (background)              B 100% fluid (inclusion)
            %     POLE                                   POLE
            %
            % High frequency (unrelaxed) elastic constants (Cij)
            % (classical DEM solid-liquid)
            % ____________________________________________
            %           ------------------------------
            %           I                  III       I
            %           I   III            III       I
            %           I   III                      I
            %           I                            I
            %           I            III             I
            %           I            III             I
            %           I                 III        I
            %           I       III       III        I
            %           I       III                  I
            %           I-----------------------------
            %
            % The high frequency unrelaxed constants correspond to UNCONNECTED
            %    fluid inclusions, hence fluid pressure is considered to be variable.
            % 
            % Calculated using the classical DEM equation
            %
            % where Cij(high frequency) = f(Csolid,Cinclusion,Vinclusion)
            %
            %   Csolid = Csc
            %   Cinclusion = Cfluid
            %   Vinclusion = volume faction of inclusion (liquid)
            %
            %   or 
            %
            %   dCdem = dVinclusion/(1-Vinclusion) (Cinclusion-Cdem) ei
            %   where ei is the strain inside the inclusion
            %
            % Low frequency (relaxed) elastic constants (Cij)
            % (classical DEM solid-empty pore plus Gassmann poro-elastic analysis)
            % ____________________________________________
            %           ------------------------------
            %           I                  III       I
            %           I   III            III       I
            %           I   III\          //         I
            %           I      \\        //          I
            %           I       \\  /III//           I
            %           I        \\//III/            I
            %           I         //    \\III        I
            %           I       III      \III        I
            %           I       III                  I
            %           I-----------------------------
            %
            % The high frequency unrelaxed constants correspond to CONNECTED
            %    fluid inclusions, hence fluid pressure is considered to be uniform.
            %
            % Step 1: Calculating the Cij of Dry (empty) porous medium using
            %         the classical DEM equation
            %
            % where Cij(dry) = f(Csolid,Cinclusion,Vinclusion)
            %
            % Csolid = Csc
            % Cinclusion = Cpore (void)
            % Vinclusion = volume faction of (empty) pores
            % 
            % Step 2: Calculate the Cij of Saturated (filled) porous medium using
            %         the anisotropic generalization of Brown and Korringa (1975) of
            %         Gassmann's (1951) poroelastic theory.
            %
            % where Cij (sat) = f(Csolid,Cdry,Bfluid,Vpore)
            %
            % Csat   = C low frequency
            % Csolid = Csc
            % Bfluid = fluid compressibility = 1/bulk modulus
            % Vpore  = volume fraction of (filled) pores = volume fraction of inclusion
            % 
            % In the program 
            %    the arrays correspond to the following elastic constant (Cij)
            %
            % Csolid(3,3) = Cij (Self-consistent average of 100 percent polycrystalline solid)
            % Cpore(3,3)  = Cij (empty pore or void)
            % Cdem1(3,3)  = Cij (high frequency solid/liquid mixture)
            % Cdem2(3,3)  = Cij (low frequency dry porous medium)
            % Csat(3,3)   = Cij (low frequency saturated porous medium)
            
            % idem  - Inclusion
            % idem2 - Background medium
            if idem == 1 
                idem2 = 2;
            elseif idem == 2
                idem2 = 1;
            end
            
            % 'Inclusion is'
            app.InclusionisEditField.Value = app.InclusionNameEditField.Value;
            
            % Background medium
            Cbg = squeeze(ECM(idem2,:,:)); % GPa
            Csolid(:,:) = Cbg(:,:);
            
            %**************************************************************************
            %     INCLUSION (FLUID) VOLUME FRACTION LOOP
            %**************************************************************************
            
            % Stating values for Cdem1, Cpore and Cdem2
            % Starting value of Cdem = Csc
            
            % Mineral A the host (background)
            Cdem1(:,:) = Cbg(:,:);
        
            % Porous Mineral A the host (background)
            Cdem2(:,:) = Cbg(:,:);
             
            % Elastic constants for pore
            Cpore(:,:) = 0;
            
            % Starting position 99.9:0.1
            v1 = 0.001;
            v2 = 0.999;
            
            % Mineral 'idem' chosen as (fluid) inclusion
            MIN = idem;
            
            % No. of integration steps
            nstep = (v2-v1)*1000;
            
            % Step size
            dVb = (v2-v1)/(nstep);
            
            % Starting value v1 will correspond to Vb in loop 13
            % app.Vb(1,1) = v1;
            % dVb
            app.VolumeincrementstepsizedVbEditField.Value = dVb;
            
            % vliq: Max. vol. Fraction liq. (0-1), dvb: step size
            nstep2 = fix(vliq/dVb); 
            %' nstep2 = %d \n',nstep2
            app.TotalstepsEditField.Value = nstep2;
            
            % Fluid inclusion parameters for DEM loop
            % Ellipsoidal axes for (fluid) inclusion
            eaxis(1,:) = axis(MIN,:); 
            
            % Elastic constants for (fluid) inclusion
            EC(:,:) = ECM(MIN,:,:);
            
            % Rotation matrix RBACK for (fluid) Inclusion
            % Background - shape parallel specimen axes
            RBACK(:,:) = REMIN(MIN,:,:);
            
            % Inverse rotation matrix for (background) matrix RBACKI
            RBACKI(:,:) = RBACK';
            
            % No rotate of (fluid) inclusion elastic constants
            % as fluid is isotropic ECij = CGij
            CG(:,:) = EC(:,:);
            
            %**************************************************************************
            %                   INCLUSION (FLUID) VOLUME FRACTION LOOP                *
            %**************************************************************************
            
            app.vpxl = zeros(nstep2,1);
            app.vpyl = zeros(nstep2,1);
            app.vpzl = zeros(nstep2,1);
            app.vs1xl = zeros(nstep2,1);
            app.vs1yl = zeros(nstep2,1);
            app.vs1zl = zeros(nstep2,1);
            app.vs2xl = zeros(nstep2,1);
            app.vs2yl = zeros(nstep2,1);
            app.vs2zl = zeros(nstep2,1);
            
            app.vpxh = zeros(nstep2,1);
            app.vpyh = zeros(nstep2,1);
            app.vpzh = zeros(nstep2,1);
            app.vs1xh = zeros(nstep2,1);
            app.vs1yh = zeros(nstep2,1);
            app.vs1zh = zeros(nstep2,1);
            app.vs2xh = zeros(nstep2,1);
            app.vs2yh = zeros(nstep2,1);
            app.vs2zh = zeros(nstep2,1);   
            
            app.qpx = zeros(nstep2,1);
            app.qpy = zeros(nstep2,1);
            app.qpz = zeros(nstep2,1);
            app.qs1x = zeros(nstep2,1);
            app.qs1y = zeros(nstep2,1);
            app.qs1z = zeros(nstep2,1);
            app.qs2x = zeros(nstep2,1);
            app.qs2y = zeros(nstep2,1);
            app.qs2z = zeros(nstep2,1);               
            
            app.Vb = zeros(nstep2,1); 
            % Starting value v1 will correspond to Vb in loop 13
            app.Vb(1,1) = v1;                     
            
            for  k = 1:nstep2
                if k > 1
                    % Computational values of volume fraction Vb=fluid, Va=solid
                    app.Vb(k,1) = app.Vb(k-1,1) + dVb;
                end
                Va = 1 - app.Vb(k,1);


                % Error analysis for Green's Tensor
                % Mineral form in background medium Cdem2 (dry) porous
                [nb,x,w,ntcheb] = error4G(app,Cdem2,errmax,eaxis);    
                
                % Rotate DEM (background) matrix C* 
                % (high frequency Cij of solid/liquid mixture)
                [Cdemr1] = rot(app,l1,l2,ijkl,RBACK,Cdem1);
    
                % Rotate DEM (background) matrix C* 
                % (dry porous medium)
                [Cdemr2] = rot(app,l1,l2,ijkl,RBACK,Cdem2);
                
                % Solve Eshelby inclusion problem for Green's tensor Gij (Gt1 and Gt2)
                [GtR1] = green3(app,Cdemr1,eaxis,x,w,ntcheb);
                [GtR2] = green3(app,Cdemr2,eaxis,x,w,ntcheb);
    
                % Rotate Gij to (background) matrix/specimen axes
                [Gt1] = rot(app,l1,l2,ijkl,RBACKI,GtR1);
                [Gt2] = rot(app,l1,l2,ijkl,RBACKI,GtR2);
    
                % (dC/dVb) = 1/(1-Vb)*(Ci-Cdem(Vb))*inv(I+G(Ci-Cdem(Vb))
                % high frequency solid/liquid mixture
                [Cdem1] = difdem2(app,Cdem1,app.Vb(k,1),CG,Gt1,dVb);
                
                % Low frequency dry porous solid
                [Cdem2] = difdem2(app,Cdem2,app.Vb(k,1),Cpore,Gt2,dVb);
    
                % Poro-elastic formulation
                % dry porous solid
                Cdry(:,:) = Cdem2(:,:);
                
                % Gassmann's (1951) poro-elastic formula for saturated porous medium
                vpore = app.Vb(k,1);    
                [Csat] = gasman(app,Cdry,Csolid,bf,vpore,Csat);
            
                % Density = Fluid(inclusion) + Rock(background) density
                drock = app.Vb(k,1)*rho(idem) + Va*rho(idem2);
                
                % Porous dry (unfilled pores) rock density
                rhodry = Va*rho(idem2);
                   
                % Cij [GPa]
                Clow(:,:)  = Csat(:,:);
                Chigh(:,:) = Cdem1(:,:);
                Cdry(:,:)  = Cdry(:,:);   
                
                % Low frequency phase velocites in X,Y,Z directions
                % bulkl [GPa]; shearl [GPa]; vpxl [km/s]; drock [g/cm3] 
                [bulkl,shearl,app.vpxl(k,1),app.vs1xl(k,1),app.vs2xl(k,1),...
                              app.vpyl(k,1),app.vs1yl(k,1),app.vs2yl(k,1),...
                              app.vpzl(k,1),app.vs1zl(k,1),app.vs2zl(k,1)] = ...
                    xyzv(app,Clow,drock,ijkl);                       
                
                    
                % High frequency phase velocites in X,Y,Z directions
                % bulkh [GPa]; shearh [GPa]; vpxh [km/s]; drock [g/cm3]  
                [bulkh,shearh,app.vpxh(k,1),app.vs1xh(k,1),app.vs2xh(k,1),...
                              app.vpyh(k,1),app.vs1yh(k,1),app.vs2yh(k,1),...
                              app.vpzh(k,1),app.vs1zh(k,1),app.vs2zh(k,1)] = ...
                    xyzv(app,Chigh,drock,ijkl);                                                 
                
                
                % Estimate Fmax    
                % Calclulate moduli associated with P and S waves in X,Y,Z directions
                % bulk [GPa]; shear [GPa]; cvpx [GPa]; rhodry [g/cm3]  
                [bulk,shear,cvpx,cvs1x,cvs2x,cvpy,cvs1y,cvs2y,cvpz,cvs1z,cvs2z] = ...
                    xyzv3(app,Cdry,rhodry,ijkl);          
                    
                % Calclulate fmax associated with P waves in X,Y,Z directions
                % cvpx [GPa]; visc [Pa.s]; vpf [km/s]; rhof [g/cm3]; fvpx [1/s] 
                [fvpx,fvpy,fvpz] = fmax3(app,cvpx,cvpy,cvpz,visc,vpf,rhof);       
                
                % 1/Qs and 1/Qp max
                % drock [g/cm3]; vpxl [km/s]; vpxh [km/s]; 
                [app.qpx(k,1),app.qs1x(k,1),app.qs2x(k,1)] = ...
                    qinv(app,drock,app.vpxl(k,1),app.vs1xl(k,1),app.vs2xl(k,1),...
                                   app.vpxh(k,1),app.vs1xh(k,1),app.vs2xh(k,1));
                [app.qpy(k,1),app.qs1y(k,1),app.qs2y(k,1)] = ...
                    qinv(app,drock,app.vpyl(k,1),app.vs1yl(k,1),app.vs2yl(k,1),...
                                   app.vpyh(k,1),app.vs1yh(k,1),app.vs2yh(k,1));
                [app.qpz(k,1),app.qs1z(k,1),app.qs2z(k,1)] = ...
                    qinv(app,drock,app.vpzl(k,1),app.vs1zl(k,1),app.vs2zl(k,1),...
                                   app.vpzh(k,1),app.vs1zh(k,1),app.vs2zh(k,1));
    
    
                %**********************************************************************
                %                             PRINT SECTION                           *
                %**********************************************************************                    
                
                % Write to screen and file
                vbp = 100*app.Vb(k,1);
                fprintf(fid_16,[repmat('%12.4f \t',1,23),'\n'],...
                                vbp,drock,...
                                app.vpxl(k,1),app.vs1xl(k,1),app.vs2xl(k,1),...
                                app.vpyl(k,1),app.vs1yl(k,1),app.vs2yl(k,1),...
                                app.vpzl(k,1),app.vs1zl(k,1),app.vs2zl(k,1),...
                                app.qpx(k,1),app.qs1x(k,1),app.qs2x(k,1),...
                                app.qpy(k,1),app.qs1y(k,1),app.qs2y(k,1),...
                                app.qpz(k,1),app.qs1z(k,1),app.qs2z(k,1),...
                                fvpx,fvpy,fvpz);
                fprintf(fid_17,[repmat('%12.4f \t',1,23),'\n'],...
                                vbp,drock,...
                                app.vpxh(k,1),app.vs1xh(k,1),app.vs2xh(k,1),...
                                app.vpyh(k,1),app.vs1yh(k,1),app.vs2yh(k,1),...
                                app.vpzh(k,1),app.vs1zh(k,1),app.vs2zh(k,1),...
                                app.qpx(k,1),app.qs1x(k,1),app.qs2x(k,1),...
                                app.qpy(k,1),app.qs1y(k,1),app.qs2y(k,1),...
                                app.qpz(k,1),app.qs1z(k,1),app.qs2z(k,1),...
                                fvpx,fvpy,fvpz);
                fprintf(fid_18,[repmat('%12.4f \t',1,22),'\n'],...
                                app.Vb(k,1),Clow(1,1:6),Clow(2,2:6),Clow(3,3:6), ...
                                   Clow(4,4:6),Clow(5,5:6),Clow(6,6));
                fprintf(fid_19,[repmat('%12.4f \t',1,22),'\n'],...
                                app.Vb(k,1),Chigh(1,1:6),Chigh(2,2:6),Chigh(3,3:6), ...
                                   Chigh(4,4:6),Chigh(5,5:6),Chigh(6,6));
    
                                   
                % Write elastic constants and velocities Clow to fid_20 and fid_30   
                iprint = 10; % (e.g. iprint = nstep/100)
            
                if (fix(k/iprint)*iprint-k) == 0
                
                    ivf = fix(vbp);
                    
                    % Convert numerical value percent volume fraction 'VbP' to text 'textVF'
                    textvf = num2str(ivf);
                    cijfile = [outputDir,deblank(title),'-Clow',deblank(textvf),'.txt'];
                    
                    app.ProgressLamp.Color = [0,1,0];
                    app.ProgressEditField.Value = 'Processing...';
                    
                    fid_20 = fopen(cijfile,'w+');
                    
                    % Write elastic constants Clow to fid_20
                    fprintf(fid_20,'%s \n',[deblank(title),'-Clow',deblank(textvf),'.txt']);
                    fprintf(fid_20, ...
                        ['Cij low in GPa : Volume Fraction of liquid = ','%6.2f',' density = ','%7.4f g/cm3\n'],...
                        app.Vb(k,1),drock);
                        
                    for j = 1:3
                        a(j) = 1;
                        e(j) = 90;
                    end
                        
                    indexx = 1;
                    fprintf(fid_20,...
                           [repmat('%8.4f',1,6),'%1i','\n ', ...
                            repmat('%8.2f',1,6),'\n ',repmat('%8.2f',1,6),'\n ', ...
                            repmat('%8.2f',1,6),'\n ',repmat('%8.2f',1,6),'\n ', ...
                            repmat('%8.2f',1,6),'\n ',repmat('%8.2f',1,6) ' \n'],...
                            a,e,indexx,Clow);
                    
                    % Close fid_20
                    fclose(fid_20);
                        
                    % Write seismic velocities Clow to fid_30        
                    [~,~,~,~,~] = vpfile(app,Clow,drock,ihemi,icont,outputDir,title,textvf,ijkl);
                        
                    % 1/Qmax
                    dvp = 200*(app.vpxh(k,1)-app.vpxl(k,1))/(app.vpxh(k,1)+app.vpxl(k,1));
                        
                    fprintf(1,['%d  Vb = %0.15g \n',...
                               '    VpXL = %0.15g   VpXH = %0.15g   dVp  = %0.15g \n',...
                               '    QpX  = %0.15g   Qs1X = %0.15g   Qs2X = %0.15g \n',...
                               '    QpY  = %0.15g   Qs1Y = %0.15g   Qs2Y = %0.15g \n',...
                               '    QpZ  = %0.15g   Qs1Z = %0.15g   Qs2Z = %0.15g \n\n'],...
                               k,app.Vb(k,1),app.vpxl(k,1),app.vpxh(k,1),dvp,...
                               app.qpx(k,1),app.qs1x(k,1),app.qs2x(k,1),...
                               app.qpy(k,1),app.qs1y(k,1),app.qs2y(k,1),...
                               app.qpz(k,1),app.qs1z(k,1),app.qs2z(k,1));
                end
                %**********************************************************************
                %                      END OF PRINT SECTION                           *
                %**********************************************************************   
            end
            
            %**************************************************************************
            %            END OF INCLUSION (FLUID) VOLUME FRACTION LOOP                *
            %**************************************************************************
            
            
            % Close fid_16,17,18,19
            fclose(fid_16);
            fclose(fid_17);
            fclose(fid_18);
            fclose(fid_19);
            
            %**************************************************************************
            %                             END OF DEM LOOP                             *
            %**************************************************************************
            app.ProgressLamp.Color = [0,0,0];
            app.ProgressEditField.Value = 'Completed';
            
        end

        % Value changed function: InputfilesdirectoryEditField
        function InputFilesDirectoryValueChangedFcn(app, event)
            value = app.InputfilesdirectoryEditField.Value;
            if exist(value,'dir') == 0
                mkdir(value);
                addpath(genpath(value));
            else
                addpath(genpath(value));
            end
        end

        % Value changed function: DirectoryEditField
        function DirectoryValueChangedFcn(app, event)
            value = app.DirectoryEditField.Value;
            if exist(value,'dir') == 0
                mkdir(value);
                addpath(genpath(value));
            else
                addpath(genpath(value));
            end
        end

        % Value changed function: GassDemdirectoryEditField
        function GassDemDirectoryValueChangedFcn(app, event)
            value = app.GassDemdirectoryEditField.Value;
            if exist(value,'dir') == 0
                mkdir(value);
                addpath(genpath(value));
            else
                addpath(genpath(value));
            end
            
            
        end

        % Value changed function: InclusionisEditField
        function InclusionisValueChangedFcn(app, event)
            
            app.InclusionisEditField.BackgroundColor = 'none';
            app.InclusionisEditField.EdgeColor = 'none';
        end

        % Value changed function: VolumeincrementstepsizedVbEditField
        function VolumeincrementstepsizedVbEditFieldFcn(app, event)
            
            app.VolumeincrementstepsizedVbEditField.BackgroundColor = 'none';
            app.VolumeincrementstepsizedVbEditField.EdgeColor = 'none';
        end

        % Value changed function: TotalstepsEditField
        function TotalstepsEditFieldValueChangedFcn(app, event)
            
            app.TotalstepsEditField.BackgroundColor = 'none';
            app.TotalstepsEditField.EdgeColor = 'none';
        end

        % Value changed function: ProgressEditField
        function ProgressEditFieldFcn(app, event)
            
            app.ProgressEditField.BackgroundColor = 'none';
            app.ProgressEditField.EdgeColor = 'none';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushedFcn(app, event)
            % Plot
            cla(app.DEMresultsVpUIAxes,'reset');
            cla(app.DEMresultsVsUIAxes,'reset');
            
            % Structural direction X,Y,Z
            % Velocity in X,Y,or Z                
            switch app.StructuraldirectionDropDown.Value
                case 'X'
                    vph = app.vpxh;
                    vpl = app.vpxl;
                    qp = app.qpx;
                    
                    switch app.ShearwavedirectionDropDown.Value
                        case 'Fast' 
                            vsh = app.vs1xh;
                            vsl = app.vs1xl;
                            qs = app.qs1x;
                            
                        case 'Slow'
                            vsh = app.vs2xh;
                            vsl = app.vs2xl;
                            qs = app.qs2x;
                    end
                case 'Y'                
                    vph = app.vpyh;
                    vpl = app.vpyl;
                    qp = app.qpy;
                    
                    switch app.ShearwavedirectionDropDown.Value
                        case 'Fast' 
                            vsh = app.vs1yh;
                            vsl = app.vs1yl;
                            qs = app.qs1y;
                            
                        case 'Slow'
                            vsh = app.vs2yh;
                            vsl = app.vs2yl;
                            qs = app.qs2y;
                    end
                case 'Z'
                    vph = app.vpzh;
                    vpl = app.vpzl;
                    qp = app.qpz;
                    
                    switch app.ShearwavedirectionDropDown.Value
                        case 'Fast' 
                            vsh = app.vs1zh;
                            vsl = app.vs1zl;
                            qs = app.qs1z;
                            
                        case 'Slow'
                            vsh = app.vs2zh;
                            vsl = app.vs2zl;
                            qs = app.qs2z;
                    end
            end
       
            % plot vpl on DEM results axes
            % Vp
            %figDEMVp = uifigure;
            %figDEMVp.Name = 'DEM results';
            
            yyaxis(app.DEMresultsVsUIAxes,'left');
            
            if app.Maxvolumefractionofinclusion01EditField.Value == app.VolumefractionsMaxEditField.Value
                pvpl = plot(app.DEMresultsVpUIAxes,app.Vb(1:end-1,1),vpl(1:end-1,1));
            else
                pvpl = plot(app.DEMresultsVpUIAxes,app.Vb(:,1),vpl(:,1));
            end
            %             pvpl.Marker = 'o';
            %             pvpl.MarkerSize = 0.4;
            %             pvpl.MarkerEdgeColor = 'b';
            %             pvpl.MarkerFaceColor = 'b';
            pvpl.LineStyle = '-';
            pvpl.LineWidth = 1;
            pvpl.Color = [0.25,0.41,0.88];
            hold(app.DEMresultsVpUIAxes,'on');
            
            % plot vph on DEM results axes
            if app.Maxvolumefractionofinclusion01EditField.Value == app.VolumefractionsMaxEditField.Value            
                pvph = plot(app.DEMresultsVpUIAxes,app.Vb(1:end-1,1),vph(1:end-1,1));
            else
                pvph = plot(app.DEMresultsVpUIAxes,app.Vb,vph);
            end
            
            %             pvph.Marker = 'o';
            %             pvph.MarkerSize = 0.4;
            %             pvph.MarkerEdgeColor = 'r';
            %             pvph.MarkerFaceColor = 'r';
            pvph.LineStyle = '-';
            pvph.LineWidth = 1;
            pvph.Color = [1,0.27,0];
            hold(app.DEMresultsVpUIAxes,'on');                 
            
            yyaxis(app.DEMresultsVpUIAxes,'right');
                        
            if app.Maxvolumefractionofinclusion01EditField.Value == app.VolumefractionsMaxEditField.Value            
                pQmaxinvVp = semilogy(app.DEMresultsVpUIAxes,app.Vb(1:end-1,1),qp(1:end-1,1));
            else
                pQmaxinvVp = semilogy(app.DEMresultsVpUIAxes,app.Vb,qp);
            end
            
            %             pQmaxinvVp.Marker = 'o';
            %             pQmaxinvVp.MarkerSize = 0.4;
            %             pQmaxinvVp.MarkerEdgeColor = 'm';
            %             pQmaxinvVp.MarkerFaceColor = 'm';
            pQmaxinvVp.LineStyle = '-';
            pQmaxinvVp.LineWidth = 1;
            pQmaxinvVp.Color = [0.13,0.70,0.67];
            hold(app.DEMresultsVpUIAxes,'on');
            
            app.DEMresultsVpUIAxes.Position = [480,340,400,280]; % [x y width height]
            app.DEMresultsVpUIAxes.Box = 'on';
            app.DEMresultsVpUIAxes.Title.String = [app.HostmediumNameEditField.Value,'+',app.InclusionNameEditField.Value];
            app.DEMresultsVpUIAxes.XLabel.String = ''; 
            app.DEMresultsVpUIAxes.YAxis(1).Limits = [app.VpMinEditField.Value app.VpMaxEditField.Value];
            app.DEMresultsVpUIAxes.YAxis(2).Limits = [app.QmaxInvMinEditField.Value app.QmaxInvMaxEditField.Value];   
            app.DEMresultsVpUIAxes.XAxis(1).Limits = [app.VolumefractionsMinEditField.Value app.VolumefractionsMaxEditField.Value];  
            app.DEMresultsVpUIAxes.YAxis(1).Scale = 'linear';
            app.DEMresultsVpUIAxes.YAxis(2).Scale = 'log';                                
            app.DEMresultsVpUIAxes.YAxis(1).Label.String = 'Vp (km/s)';
            app.DEMresultsVpUIAxes.YAxis(2).Label.String = 'Qmax^{-1}';             
            app.DEMresultsVpUIAxes.YAxis(1).Color = 'k';
            app.DEMresultsVpUIAxes.YAxis(2).Color = [0.13,0.70,0.67];                        
            
            % Vs
            %figDEMVs = figure(2);
            
            yyaxis(app.DEMresultsVsUIAxes,'left');            
                        
            if app.Maxvolumefractionofinclusion01EditField.Value == app.VolumefractionsMaxEditField.Value            
                pvsl = plot(app.DEMresultsVsUIAxes,app.Vb(1:end-1,1),vsl(1:end-1,1));
            else
                pvsl = plot(app.DEMresultsVsUIAxes,app.Vb,vsl);
            end
            
            %             pvsl.Marker = 'o';
            %             pvsl.MarkerSize = 0.4;
            %             pvsl.MarkerEdgeColor = 'b';
            %             pvsl.MarkerFaceColor = 'b';
            pvsl.LineStyle = '-';
            pvsl.LineWidth = 1;
            pvsl.Color = [0.25,0.41,0.88];
            hold(app.DEMresultsVsUIAxes,'on');                           
                        
            if app.Maxvolumefractionofinclusion01EditField.Value == app.VolumefractionsMaxEditField.Value            
                pvsh = plot(app.DEMresultsVsUIAxes,app.Vb(1:end-1,1),vsh(1:end-1,1));
            else
                pvsh = plot(app.DEMresultsVsUIAxes,app.Vb,vsh);
            end
            
            %             pvsh.Marker = 'o';
            %             pvsh.MarkerSize = 0.4;
            %             pvsh.MarkerEdgeColor = 'r';
            %             pvsh.MarkerFaceColor = 'r';
            pvsh.LineStyle = '-';
            pvsh.LineWidth = 1;
            pvsh.Color = [1,0.27,0];
            hold(app.DEMresultsVsUIAxes,'on');    
            
            yyaxis(app.DEMresultsVsUIAxes,'right');
                        
            if app.Maxvolumefractionofinclusion01EditField.Value == app.VolumefractionsMaxEditField.Value            
                pQmaxinvVs = semilogy(app.DEMresultsVsUIAxes,app.Vb(1:end-1,1),qs(1:end-1,1));  
            else
                pQmaxinvVs = semilogy(app.DEMresultsVsUIAxes,app.Vb,qs);  
            end
                      
            %             pQmaxinvVs.Marker = 'o';
            %             pQmaxinvVs.MarkerSize = 0.4;
            %             pQmaxinvVs.MarkerEdgeColor = 'm';
            %             pQmaxinvVs.MarkerFaceColor = 'm';
            pQmaxinvVs.LineStyle = '-';
            pQmaxinvVs.LineWidth = 1;
            pQmaxinvVs.Color = [0.13,0.70,0.67];
            hold(app.DEMresultsVsUIAxes,'on'); 
            
            
            app.DEMresultsVsUIAxes.Position = [480,40,400,280]; % [x y width height]
            app.DEMresultsVsUIAxes.Box = 'on';
            app.DEMresultsVsUIAxes.Title.String = ''; 
            app.DEMresultsVsUIAxes.XLabel.String = 'Inclusion volume fraction'; 
            app.DEMresultsVsUIAxes.YAxis(1).Limits = [app.VsMinEditField.Value app.VsMaxEditField.Value];
            app.DEMresultsVsUIAxes.YAxis(2).Limits = [app.QmaxInvMinEditField.Value app.QmaxInvMaxEditField.Value];       
            app.DEMresultsVsUIAxes.XAxis(1).Limits = [app.VolumefractionsMinEditField.Value app.VolumefractionsMaxEditField.Value];
            app.DEMresultsVsUIAxes.YAxis(1).Scale = 'linear';
            app.DEMresultsVsUIAxes.YAxis(2).Scale = 'log';            
            app.DEMresultsVsUIAxes.YAxis(1).Label.String = 'Vs (km/s)';
            app.DEMresultsVsUIAxes.YAxis(2).Label.String = 'Qmax^{-1}';           
            app.DEMresultsVsUIAxes.YAxis(1).Color = 'k';
            app.DEMresultsVsUIAxes.YAxis(2).Color = [0.13,0.70,0.67];                            
            
            dirXYZ = app.StructuraldirectionDropDown.Value;
            switch app.ShearwavedirectionDropDown.Value
                case 'Fast'
                    dirVs = '1';
                case 'Slow'
                    dirVs = '2';
            end
            
            lg = legend(app.DEMresultsVpUIAxes,[pvpl pvph],...
                          ['Vp',dirXYZ,'L'],...
                          ['Vp',dirXYZ,'H']);
            lg.FontSize = 15;
            
            lg = legend(app.DEMresultsVsUIAxes,[pvsl pvsh],...
                          ['Vs',dirVs,dirXYZ,'L'],...
                          ['Vs',dirVs,dirXYZ,'H']);
            lg.FontSize = 15;           
            
            app.StructuraldirectionDropDown.Value
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GassDemUIFigure = uifigure;
            app.GassDemUIFigure.Name = 'GassDem';
            setAutoResize(app, app.GassDemUIFigure, true)
            app.TabGroup = uitabgroup(app.GassDemUIFigure);
            app.EffectiveMediumTab = uitab(app.TabGroup);
            app.EffectiveMediumTab.Title = 'Effective Medium';
            app.EffectiveMediumTab.Units = 'pixels';
            app.FluidpropertiesPanel = uipanel(app.EffectiveMediumTab);
            app.FluidpropertiesPanel.Title = 'Fluid properties';
            app.CompressibilityBfEditFieldLabel = uilabel(app.FluidpropertiesPanel);
            app.CompressibilityBfEditFieldLabel.Text = 'Compressibility Bf';
            app.CompressibilityBfEditField = uieditfield(app.FluidpropertiesPanel, 'numeric');
            app.CompressibilityBfEditFieldLabel_2 = uilabel(app.FluidpropertiesPanel);
            app.CompressibilityBfEditFieldLabel_2.Text = '1/GPa';
            app.ViscosityEditFieldLabel = uilabel(app.FluidpropertiesPanel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity';
            app.ViscosityEditField = uieditfield(app.FluidpropertiesPanel, 'numeric');
            app.FluidViscosityPasEditFieldLabel = uilabel(app.FluidpropertiesPanel);
            app.FluidViscosityPasEditFieldLabel.Text = 'Pa.s';
            app.VpEditFieldLabel = uilabel(app.FluidpropertiesPanel);
            app.VpEditFieldLabel.Text = 'Vp';
            app.VpEditField = uieditfield(app.FluidpropertiesPanel, 'numeric');
            app.CompressibilityBfEditFieldLabel_4 = uilabel(app.FluidpropertiesPanel);
            app.CompressibilityBfEditFieldLabel_4.Text = 'km/s';
            app.DensityEditFieldLabel = uilabel(app.FluidpropertiesPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.FluidpropertiesPanel, 'numeric');
            app.FluidDensitygcm3EditFieldLabel = uilabel(app.FluidpropertiesPanel);
            app.FluidDensitygcm3EditFieldLabel.Text = 'g/cm3';
            app.Bf1KfKfBulkmodulusGPaLabel = uilabel(app.FluidpropertiesPanel);
            app.Bf1KfKfBulkmodulusGPaLabel.Text = {'Bf = 1/Kf'; 'Kf: Bulk modulus (GPa)'};
            app.FluidCompressibilityEGLabel = uilabel(app.FluidpropertiesPanel);
            app.FluidCompressibilityEGLabel.Text = {'e.g. Water = 0.4878 1/GPa'; '       Basalt = 0.0546 1/GPa at 1233 C'};
            app.FluidViscosityEGLabel = uilabel(app.FluidpropertiesPanel);
            app.FluidViscosityEGLabel.Text = {'e.g. Water = 1.0E-03 Pa.s'; '       Basalt = 10 Pa.s'};
            app.FluidVpEGLabel = uilabel(app.FluidpropertiesPanel);
            app.FluidVpEGLabel.Text = {'e.g. Water = 1.480 km/s at 20 C'; '       Basalt = 2.610 km/s at 1233 C'};
            app.FluidDensityEGLabel = uilabel(app.FluidpropertiesPanel);
            app.FluidDensityEGLabel.Text = {'e.g. Water = 1.000 g/cm3'; '       Basalt = 2.680 g/cm3 at 1233 C'};
            app.MaxerrorforGreensTensoreg01EditFieldLabel = uilabel(app.EffectiveMediumTab);
            app.MaxerrorforGreensTensoreg01EditFieldLabel.Text = 'Max. % error for Green''s Tensor *(e.g. 0.1)*';
            app.MaxerrorforGreensTensoreg01EditField = uieditfield(app.EffectiveMediumTab, 'numeric');
            app.MaxerrorforGreensTensoreg01EditField.Limits = [0 100];
            app.TwocomponentsystemofAandBforDEMcalculationPanel = uipanel(app.EffectiveMediumTab);
            app.TwocomponentsystemofAandBforDEMcalculationPanel.Title = 'Two-component system of A and B for DEM calculation';
            app.HostmediumElasticConstantsEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'text');
            app.HostmediumDensityEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'numeric');
            app.AHostmediumEditFieldLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.AHostmediumEditFieldLabel.Text = 'A: Host medium';
            app.HostmediumNameEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'text');
            app.InclusionElasticConstantsEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'text');
            app.InclusionDensityEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'numeric');
            app.BInclusionegfluidEditFieldLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.BInclusionegfluidEditFieldLabel.Text = 'B: Inclusion (e.g. fluid)';
            app.InclusionNameEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'text');
            app.PhaseLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.PhaseLabel.Text = 'Phase';
            app.NameLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.NameLabel.Text = 'Name';
            app.ElasticconstantsfiletxtLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.ElasticconstantsfiletxtLabel.Text = 'Elastic constants file (*.txt)';
            app.Densitygcm3Label = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.Densitygcm3Label.Text = 'Density (g/cm3)';
            app.NoteVoigtelastictensorinGPaLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.NoteVoigtelastictensorinGPaLabel.Text = '(Note: Voigt elastic tensor in GPa)';
            app.InputfilesdirectoryEditFieldLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.InputfilesdirectoryEditFieldLabel.Text = 'Input files directory:';
            app.InputfilesdirectoryEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'text');
            app.InputfilesdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @InputFilesDirectoryValueChangedFcn, true);
            app.GassDemdirectoryEditFieldLabel = uilabel(app.TwocomponentsystemofAandBforDEMcalculationPanel);
            app.GassDemdirectoryEditFieldLabel.Text = 'GassDem directory:';
            app.GassDemdirectoryEditField = uieditfield(app.TwocomponentsystemofAandBforDEMcalculationPanel, 'text');
            app.GassDemdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @GassDemDirectoryValueChangedFcn, true);
            app.InclusionShapeandOrientationTab = uitab(app.TabGroup);
            app.InclusionShapeandOrientationTab.Title = 'Inclusion Shape and Orientation';
            app.InclusionShapeandOrientationTab.Units = 'pixels';
            app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup = uibuttongroup(app.InclusionShapeandOrientationTab);
            app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup.Title = 'Ellipsoid semi-axes lengths A1:A2:A3';
            app.Sphere111Button = uiradiobutton(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup);
            app.Sphere111Button.Text = 'Sphere 1:1:1';
            app.Sphere111Button.Value = true;
            app.EllipsoidButton = uiradiobutton(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup);
            app.EllipsoidButton.Text = 'Ellipsoid';
            app.EllipsoidA1EditField = uieditfield(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup, 'numeric');
            app.EllipsoidA2EditField = uieditfield(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup, 'numeric');
            app.EllipsoidA3EditField = uieditfield(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup, 'numeric');
            app.Label = uilabel(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.EllipsoidsemiaxeslengthsA1A2A3ButtonGroup);
            app.Label_2.Text = ':';
            app.OrientationofshapeellipsoidsemiaxesButtonGroup = uibuttongroup(app.InclusionShapeandOrientationTab);
            app.OrientationofshapeellipsoidsemiaxesButtonGroup.Title = 'Orientation of shape ellipsoid semi-axes';
            app.ParalleltoelastictensoraxesButton = uiradiobutton(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.ParalleltoelastictensoraxesButton.Text = 'Parallel to elastic tensor axes';
            app.ParalleltoelastictensoraxesButton.Value = true;
            app.ParalleltouserdefinedorientationSPOButton = uiradiobutton(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.ParalleltouserdefinedorientationSPOButton.Text = 'Parallel to user-defined orientation (shape preferred orientation: SPO)';
            app.Label_3 = uilabel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.Label_3.Text = {'Ellipsoid axes A // Elastic tensor axes X'; ''; '   A1 // X1 (e.g. olivine = [100]: North)'; '   A2 // X2 (e.g. olivine = [010]: East)'; '   A3 // X3 (e.g. olivine = [001]: Up)'; ''; '   X1 = 100 in olivine, X1 = a in calcite etc.'; ''; ''};
            app.SPOLabel = uilabel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.SPOLabel.Text = {'(for fluid inclusion)'; ''; 'User defined shape ellipsoid orientation'; ''; 'A1 should be 90 degrees to A3'};
            app.A1EditFieldLabel = uilabel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1AzimuthdegreeEditField = uieditfield(app.OrientationofshapeellipsoidsemiaxesButtonGroup, 'numeric');
            app.A1InclinationdegreeEditField = uieditfield(app.OrientationofshapeellipsoidsemiaxesButtonGroup, 'numeric');
            app.A3InclinationdegreeEditField = uieditfield(app.OrientationofshapeellipsoidsemiaxesButtonGroup, 'numeric');
            app.A3EditFieldLabel = uilabel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.A3EditFieldLabel.Text = 'A3';
            app.A3AzimuthdegreeEditField = uieditfield(app.OrientationofshapeellipsoidsemiaxesButtonGroup, 'numeric');
            app.A1AzimuthEditFieldLabel = uilabel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.A1AzimuthEditFieldLabel.Text = {'Azimuth'; '(degree)'};
            app.A1InclinationEditFieldLabel = uilabel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.A1InclinationEditFieldLabel.Text = {'Inclination'; '(degree)'};
            app.Parallel2XUIAxes = uiaxes(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            title(app.Parallel2XUIAxes, 'Title');
            xlabel(app.Parallel2XUIAxes, 'X');
            ylabel(app.Parallel2XUIAxes, 'Y');
            app.Parallel2XUIAxes.LineStyleOrder = {'- '};
            app.CheckButton = uibutton(app.OrientationofshapeellipsoidsemiaxesButtonGroup, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushedFcn, true);
            app.CheckButton.Text = 'Check';
            app.OrientatonisOKPanel = uipanel(app.OrientationofshapeellipsoidsemiaxesButtonGroup);
            app.OrientatonisOKPanel.Title = 'Orientaton is OK?';
            app.Check1AngleA1toA3EditFieldLabel = uilabel(app.OrientatonisOKPanel);
            app.Check1AngleA1toA3EditFieldLabel.Text = ' Check 1: Angle A1 to A3 =';
            app.Check1AngleA1toA3EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.Check1AngleA1toA3EditField.Editable = 'off';
            app.Check2AngleA1toA3EditFieldLabel = uilabel(app.OrientatonisOKPanel);
            app.Check2AngleA1toA3EditFieldLabel.Text = ' Check 2: Angle A1 to A3 = ';
            app.Check2AngleA1toA3EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.Check2AngleA1toA3EditField.Editable = 'off';
            app.DetRLabel = uilabel(app.OrientatonisOKPanel);
            app.DetRLabel.Text = 'Det|R| =';
            app.Check3DetRshouldbe1Label = uilabel(app.OrientatonisOKPanel);
            app.Check3DetRshouldbe1Label.Text = 'Check 3: Det|R| should be +1';
            app.Check3DetRshouldbe1EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.Check3DetRshouldbe1EditField.Editable = 'off';
            app.FixedorientationofshapeellipsoidaxesLabel = uilabel(app.OrientatonisOKPanel);
            app.FixedorientationofshapeellipsoidaxesLabel.Text = 'Fixed orientation of shape ellipsoid axes';
            app.A1AZLabel = uilabel(app.OrientatonisOKPanel);
            app.A1AZLabel.Text = 'A1:   AZ = ';
            app.A1AZEditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.A1AZEditField.Editable = 'off';
            app.INCEditFieldLabel = uilabel(app.OrientatonisOKPanel);
            app.INCEditFieldLabel.Text = 'INC =';
            app.A1INCEditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.A1INCEditField.Editable = 'off';
            app.A2AZLabel = uilabel(app.OrientatonisOKPanel);
            app.A2AZLabel.Text = 'A2:   AZ = ';
            app.A2AZEditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.A2AZEditField.Editable = 'off';
            app.INCEditField_2Label = uilabel(app.OrientatonisOKPanel);
            app.INCEditField_2Label.Text = 'INC =';
            app.A2INCEditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.A2INCEditField.Editable = 'off';
            app.A3AZLabel = uilabel(app.OrientatonisOKPanel);
            app.A3AZLabel.Text = 'A3:   AZ = ';
            app.A3AZEditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.A3AZEditField.Editable = 'off';
            app.INCEditField_3Label = uilabel(app.OrientatonisOKPanel);
            app.INCEditField_3Label.Text = 'INC =';
            app.A3INCEditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.A3INCEditField.Editable = 'off';
            app.ShapeellipsoidrotationmatrixLabel = uilabel(app.OrientatonisOKPanel);
            app.ShapeellipsoidrotationmatrixLabel.Text = 'Shape ellipsoid rotation matrix';
            app.ShapeEllipsoidRotationMatrix11EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix11EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix12EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix12EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix13EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix13EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix21EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix21EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix22EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix22EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix23EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix23EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix31EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix31EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix32EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix32EditField.Editable = 'off';
            app.ShapeEllipsoidRotationMatrix33EditField = uieditfield(app.OrientatonisOKPanel, 'numeric');
            app.ShapeEllipsoidRotationMatrix33EditField.Editable = 'off';
            app.repeattoroundofferrorsLabel = uilabel(app.OrientatonisOKPanel);
            app.repeattoroundofferrorsLabel.Text = '(repeat to round-off errors)';
            app.AZAzimuthINCInclinationLabel = uilabel(app.OrientatonisOKPanel);
            app.AZAzimuthINCInclinationLabel.Text = '(AZ: Azimuth, INC: Inclination)';
            app.DEMAnalysisTab = uitab(app.TabGroup);
            app.DEMAnalysisTab.Title = 'DEM Analysis';
            app.DEMAnalysisTab.Units = 'pixels';
            app.CalculateButton = uibutton(app.DEMAnalysisTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushedFcn, true);
            app.CalculateButton.Text = 'Calculate';
            app.Maxvolumefractionofinclusion01EditFieldLabel = uilabel(app.DEMAnalysisTab);
            app.Maxvolumefractionofinclusion01EditFieldLabel.Text = 'Max. volume fraction of inclusion (0-1)';
            app.Maxvolumefractionofinclusion01EditField = uieditfield(app.DEMAnalysisTab, 'numeric');
            app.Maxvolumefractionofinclusion01EditField.Limits = [0 1];
            app.FilenameanddirectoryforDEMresultsPanel = uipanel(app.DEMAnalysisTab);
            app.FilenameanddirectoryforDEMresultsPanel.Title = 'File name and directory for DEM results';
            app.FilenameEditFieldLabel = uilabel(app.FilenameanddirectoryforDEMresultsPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.FilenameanddirectoryforDEMresultsPanel, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.FilenameanddirectoryforDEMresultsPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FilenameanddirectoryforDEMresultsPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryValueChangedFcn, true);
            app.CurrentstatusPanel = uipanel(app.DEMAnalysisTab);
            app.CurrentstatusPanel.Title = 'Current status';
            app.InclusionisEditFieldLabel = uilabel(app.CurrentstatusPanel);
            app.InclusionisEditFieldLabel.Text = 'Inclusion is';
            app.InclusionisEditField = uieditfield(app.CurrentstatusPanel, 'text');
            app.InclusionisEditField.ValueChangedFcn = createCallbackFcn(app, @InclusionisValueChangedFcn, true);
            app.InclusionisEditField.Editable = 'off';
            app.VolumeincrementstepsizedVbEditFieldLabel = uilabel(app.CurrentstatusPanel);
            app.VolumeincrementstepsizedVbEditFieldLabel.Text = 'Volume increment step size, dVb = ';
            app.VolumeincrementstepsizedVbEditField = uieditfield(app.CurrentstatusPanel, 'numeric');
            app.VolumeincrementstepsizedVbEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeincrementstepsizedVbEditFieldFcn, true);
            app.VolumeincrementstepsizedVbEditField.Editable = 'off';
            app.TotalstepsEditFieldLabel = uilabel(app.CurrentstatusPanel);
            app.TotalstepsEditFieldLabel.Text = 'Total steps = ';
            app.TotalstepsEditField = uieditfield(app.CurrentstatusPanel, 'numeric');
            app.TotalstepsEditField.ValueChangedFcn = createCallbackFcn(app, @TotalstepsEditFieldValueChangedFcn, true);
            app.TotalstepsEditField.Editable = 'off';
            app.ProgressEditField = uieditfield(app.CurrentstatusPanel, 'text');
            app.ProgressEditField.ValueChangedFcn = createCallbackFcn(app, @ProgressEditFieldFcn, true);
            app.ProgressEditField.Editable = 'off';
            app.ProgressLabel = uilabel(app.CurrentstatusPanel);
            app.ProgressLabel.Text = 'Progress:';
            app.ProgressLamp = uilamp(app.CurrentstatusPanel);
            app.FigureconfigurationPanel = uipanel(app.DEMAnalysisTab);
            app.FigureconfigurationPanel.Title = 'Figure configuration';
            app.VolumefractionsEditFieldLabel = uilabel(app.FigureconfigurationPanel);
            app.VolumefractionsEditFieldLabel.Text = 'Vp (km/s)';
            app.VpMinEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.VpMinEditField.Value = 3.5;
            app.VpMaxEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.VpMaxEditField.Value = 8;
            app.VolumefractionsEditFieldLabel_2 = uilabel(app.FigureconfigurationPanel);
            app.VolumefractionsEditFieldLabel_2.Text = 'Max';
            app.VolumefractionsMaxEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.VolumefractionsMaxEditField.Value = 0.2;
            app.VolumefractionsEditFieldLabel_3 = uilabel(app.FigureconfigurationPanel);
            app.VolumefractionsEditFieldLabel_3.Text = 'Min';
            app.VolumefractionsMinEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.VolumefractionsEditFieldLabel_4 = uilabel(app.FigureconfigurationPanel);
            app.VolumefractionsEditFieldLabel_4.Text = 'Volume fractions';
            app.StructuraldirectionLabel_2 = uilabel(app.FigureconfigurationPanel);
            app.StructuraldirectionLabel_2.Text = {'X: lineation'; 'Y: normal to the lineation in the foliation plane '; 'Z: normal to the foliation plane'};
            app.ShearwavedirectionDropDownLabel = uilabel(app.FigureconfigurationPanel);
            app.ShearwavedirectionDropDownLabel.Text = 'Shear wave direction';
            app.ShearwavedirectionDropDown = uidropdown(app.FigureconfigurationPanel);
            app.ShearwavedirectionDropDown.Items = {'Fast', 'Slow'};
            app.ShearwavedirectionDropDown.Value = 'Fast';
            app.StructuraldirectionDropDownLabel = uilabel(app.FigureconfigurationPanel);
            app.StructuraldirectionDropDownLabel.Text = 'Structural direction';
            app.StructuraldirectionDropDown = uidropdown(app.FigureconfigurationPanel);
            app.StructuraldirectionDropDown.Items = {'X', 'Y', 'Z'};
            app.StructuraldirectionDropDown.Value = 'Z';
            app.VolumefractionsEditFieldLabel_5 = uilabel(app.FigureconfigurationPanel);
            app.VolumefractionsEditFieldLabel_5.Text = 'Vs (km/s)';
            app.VsMinEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.VsMinEditField.Value = 1.5;
            app.VsMaxEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.VsMaxEditField.Value = 4.5;
            app.StructuraldirectionLabel_3 = uilabel(app.FigureconfigurationPanel);
            app.StructuraldirectionLabel_3.Text = {'Vs1: fast shear velocity'; 'Vs2: slow shear velocity'};
            app.QmaxInvEditFieldLabel = uilabel(app.FigureconfigurationPanel);
            app.QmaxInvEditFieldLabel.Text = '1/Qmax';
            app.QmaxInvMinEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.QmaxInvMinEditField.Value = 1e-05;
            app.QmaxInvMaxEditField = uieditfield(app.FigureconfigurationPanel, 'numeric');
            app.QmaxInvMaxEditField.Value = 0.1;
            app.PlotButton = uibutton(app.FigureconfigurationPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushedFcn, true);
            app.PlotButton.Text = 'Plot';
            app.Note1QmaxisplottedassemilogscaleLabel = uilabel(app.FigureconfigurationPanel);
            app.Note1QmaxisplottedassemilogscaleLabel.Text = '(Note: 1/Qmax is plotted as semi-log scale)';
            app.DEMresultsVpUIAxes = uiaxes(app.DEMAnalysisTab);
            title(app.DEMresultsVpUIAxes, 'Title');
            xlabel(app.DEMresultsVpUIAxes, 'X');
            ylabel(app.DEMresultsVpUIAxes, 'Y');
            app.DEMresultsVsUIAxes = uiaxes(app.DEMAnalysisTab);
            title(app.DEMresultsVsUIAxes, 'Title');
            xlabel(app.DEMresultsVsUIAxes, 'X');
            ylabel(app.DEMresultsVsUIAxes, 'Y');
            app.NoteHHighfrequencyLLowfrequencyLabel = uilabel(app.DEMAnalysisTab);
            app.NoteHHighfrequencyLLowfrequencyLabel.Text = '(Note: H - High frequency; L - Low frequency)';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpTab.Units = 'pixels';
            app.TabGroup2 = uitabgroup(app.HelpTab);
            app.AboutGassDemTab = uitab(app.TabGroup2);
            app.AboutGassDemTab.Title = 'About GassDem';
            app.AboutGassDemTab.Units = 'pixels';
            app.Label_5 = uilabel(app.AboutGassDemTab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.AboutGassDemTab);
            app.Label_6.Text = {'   - GassDem (Gassmann Differential effective medium) GUI is designed for an anisotropic elastic solid A with variable amounts of liquid B with '; '     a shape specified by an ellipsoid of arbitrary orientation with semi-axes of variable lengths parallel to X, Y, Z described in'; '     Mainprice''s (1997) "Modelling the anisotropic seismic properties of partially molten rocks found at Mid-Ocean Ridges", '; '     Tectonophysics 279, 161-179.'; ''; '   - GassDem GUI and analytical schemes are described in the paper of '; '     Kim, E., Kim, Y., and Mainprice, D. (2019), "GassDem: A MATLAB program for modeling the anisotropic seismic properties of '; '     porous medium using differential effective medium theory and Gassmann’s poroelastic relationship", Computers and Geosciences.'; ''; ''; '     MATLAB version LAST REVISED: February 26, 2019'; '     EUNYOUNG KIM'; '     Seoul National University, Seoul, Republic of Korea'; '     e-mail: brilliant@snu.ac.kr'};
            app.InputsTab = uitab(app.TabGroup2);
            app.InputsTab.Title = 'Inputs';
            app.InputsTab.Units = 'pixels';
            app.Label_9 = uilabel(app.InputsTab);
            app.Label_9.Text = {''; ''};
            app.Label_8 = uilabel(app.InputsTab);
            app.Label_8.Text = {'   - Elastic constants of host medium can be made by the self-consistent (SC) approximation or any other numerical methods as well as experiments.'; ''; '   - For inclusions with a shape of sphere, the user can select semi-axes parallel to elastic tensor axes, '; '     because user-defined orientation should make same results. '; ''; '   - For fluid inclusion, fluid compressibility Bf is 1/Kf. Note that fluid bulk modulus Kf should be consistent with elastic constants of fluid inclusion.'; ''; '   - Elastic constant data files '; ''; '     e.g. Morales2018_ModelSC3_GPa.txt'; ''; '            Model SC3 Elastic Matrix (GPa) Morales et al. JGR 2018           : Name and reference      '; '            Antigorite 78% Olivine 22% room pressure d=2.7292 (g/cm3)   : Composition and physical properties                   '; '            4.7646 10.2296 5.9942 90.0000 90.0000 90.00001                    : a,b,c, alpha,beta,gamma plus index* '; '            97.81	  33.28   27.62   0.33   0.20   -0.11                                   : Cij, i=1:6, j=1:6'; '            33.28	 159.96	  45.85  -0.43  -0.49   -0.66                              '; '            27.62	  45.85	 144.48  -0.74  -2.26   -0.42'; '             0.33	  -0.43	  -0.74  50.19  -0.30   -0.55'; '             0.20	  -0.49	  -2.26  -0.30  28.47    0.07'; '            -0.11	  -0.66	  -0.42  -0.55   0.07   32.18'; ''; '       *Note: 4.7646 10.2296 5.9942 90.0000 90.0000 90.00001      '; '                  Third line of elastic constant data file is '; '                  a format for unit cell for single crystal (a,b,c, alpha,beta,gamma plus 1 for nonhexagonal or '; '                  2 which allows to identify the hexagonal symmetry) elastic constants, it is not used for '; '                  polycrystalline elastic constants of isotropic of Harziso.mb.'; '          '; ''; '   - We note that the file separator character is a backslash (\) on Windows platforms, whereas it is a forward slash (/) on UNIX platforms '; '     (https://www.mathworks.com/help/matlab/ref/fullfile.html).'; '      '; '      e.g. Windows:'; '                  C:\Users\EKim\Documents\GassDem\'; '             UNIX:  '; '                  /Users/ekim/Documents/GassDem/ '; ' '};
            app.OutputsTab = uitab(app.TabGroup2);
            app.OutputsTab.Title = 'Outputs';
            app.OutputsTab.Units = 'pixels';
            app.OutputfilesPanel = uipanel(app.OutputsTab);
            app.OutputfilesPanel.Title = 'Output files';
            app.OutputFilesDescriptionUITable = uitable(app.OutputfilesPanel);
            app.OutputFilesDescriptionUITable.ColumnName = {'Output file'; 'Description'};
            app.OutputFilesDescriptionUITable.RowName = {};
            app.HelpOutputsNoteLabel = uilabel(app.OutputfilesPanel);
            app.HelpOutputsNoteLabel.Text = {'Note: '; '- Elastic stiffness tensor C (Voigt notation in GPa); Density (g/cm3); Velocity (km/s).'; '- VpXH, VpYH and VpZH are high frequency Vp in X, Y and Z directions, respectively.'; '- VpXL, VpYL and VpZL are low frequency Vp in X, Y, and Z directions, respectively.'; '- Vs1XH, Vs1XH and Vs1XH are high frequency fast Vs in X, Y and Z directions, respectively.'; '- Vs1XL, Vs1XL and Vs1XL are low frequency fast Vs in X, Y, and Z directions, respectively.'; '- Vs2XH, Vs2XH and Vs2XH are high frequency slow Vs in X, Y and Z directions, respectively.'; '- Vs2XL, Vs2XL and Vs2XL are low frequency slow Vs in X, Y, and Z directions, respectively.'; ''; ''; ''};
            app.CodesTab = uitab(app.TabGroup2);
            app.CodesTab.Title = 'Codes';
            app.CodesTab.Units = 'pixels';
            app.Label_11 = uilabel(app.CodesTab);
            app.Label_11.Text = {'   - GassDem can be run using a script (runGassDem.m) instead of GUI. '; ''; '      Step 1: Open runGassDem.m'; ''; '      Step 2: Type input parameters in the user-defined section.'; ''; '      Step 3: Run.'; ' '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GassDem()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GassDemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GassDemUIFigure)
        end
    end
end